classdef classifierGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        DisplaystatisticsButton         matlab.ui.control.Button
        ValidateclassifierButton        matlab.ui.control.Button
        FormattrainingsetfortrainingButton  matlab.ui.control.Button
        TrainclassifierButton           matlab.ui.control.Button
        CloseButton                     matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        ClassifierparametersTab         matlab.ui.container.Tab
        PostprocessingDropDown          matlab.ui.control.DropDown
        PostprocessingDropDownLabel     matlab.ui.control.Label
        ClassiDetailsLabel              matlab.ui.control.Label
        PostprocessingfunctionhandleEditField  matlab.ui.control.EditField
        PostprocessingfunctionhandleEditFieldLabel  matlab.ui.control.Label
        ChannelindexindicesforimageclassificationEditField  matlab.ui.control.EditField
        ChannelindexindicesforimageclassificationEditFieldLabel  matlab.ui.control.Label
        SpaceseparatedclassnamesEditField  matlab.ui.control.EditField
        SpaceseparatedclassnamesLabel   matlab.ui.control.Label
        ClassifieroutputtypeDropDown    matlab.ui.control.DropDown
        ClassifieroutputtypeDropDownLabel  matlab.ui.control.Label
        TypeDropDown                    matlab.ui.control.DropDown
        TypeLabel                       matlab.ui.control.Label
        TrainingprocedureparametersTab  matlab.ui.container.Tab
        UITrainingTable                 matlab.ui.control.Table
        DatasetsROIsTab                 matlab.ui.container.Tab
        removeselectedROIButton         matlab.ui.control.Button
        DeselectallButton               matlab.ui.control.Button
        SelectallButton                 matlab.ui.control.Button
        AnnotateselectedROIButton       matlab.ui.control.Button
        ImportROIsButton                matlab.ui.control.Button
        UITableData                     matlab.ui.control.Table
        SaveclassifierparametersButton  matlab.ui.control.Button
    end


    properties (Access = private)
        callingApp % Description
    end
    
    properties (Access = public)
        Data % Description % must be public to shar info with other apps
    end

    methods (Access = private)

        function  displayClassi(app) % displays the data from classi as they are

            % classi type
            typelist=app.Data.classlist{:,2};
            classiObj=app.Data.classiObj;
            app.TypeDropDown.Value=typelist{classiObj.typeid};

            % classi description
            app.ClassiDetailsLabel.Text=app.Data.classlist{classiObj.typeid,3};

            % output
            if classiObj.typeid==4 || classiObj.typeid==13 % timeseries classifiers
                app.ClassifieroutputtypeDropDown.Enable='on';
                app.ClassifieroutputtypeDropDown.Items={'Sequence-to-sequence' 'Sequence-to-one'};
                app.ClassifieroutputtypeDropDown.Value= app.ClassifieroutputtypeDropDown.Items(classiObj.output+1);
            else
                app.ClassifieroutputtypeDropDown.Items={};
                app.ClassifieroutputtypeDropDown.Enable='off' ;
            end

            % channels
            app.ChannelindexindicesforimageclassificationEditField.Value=num2str(classiObj.channel);

            % classes
            if classiObj.typeid==8
                classiObj.classes={'background','cell'};
            end
            if classiObj.typeid==11 || classiObj.typeid==12 % image regression : no classes needed
                classiObj.classes={};
                %   app.SpaceseparatedclassnamesEditField
                app.SpaceseparatedclassnamesEditField.Enable='off';
            else

            end

            if numel(classiObj.classes)
                str='';
                for i=1:numel(classiObj.classes)
                    str=[str classiObj.classes{i} ' '];
                end
                app.SpaceseparatedclassnamesEditField.Value=str;
            else
                app.SpaceseparatedclassnamesEditField.Value='';
            end

            % postprocessing functions
            outputstr='';
            % outut functions
            if classiObj.typeid==2 || classiObj.typeid==8 %numel(classiObj.outputType)
                %   aa=classiObj.outputFun
                %   class(aa)
                app.PostprocessingDropDown.Enable='on';
                if numel(classiObj.outputType)
                    outputstr=classiObj.outputType;
                else
                    outputstr='segmentation';
                end
                %  outputstr
                app.PostprocessingDropDown.Value=outputstr;
            else
                app.PostprocessingDropDown.Enable='off';
            end

            if strcmp(outputstr,'postprocessing')

                app.PostprocessingfunctionhandleEditField.Enable='on';
                if  numel(classiObj.outputFun)
                    if ischar(classiObj.outputFun)
                    app.PostprocessingfunctionhandleEditField.Value=classiObj.outputFun;
                    end
                else
                    app.PostprocessingfunctionhandleEditField.Value='@post';
                end

            else
                app.PostprocessingfunctionhandleEditField.Enable='off';
                app.PostprocessingfunctionhandleEditField.Value='';
            end


            % HERE
        end

        function  displayTraining(app)
            % displays training parameters as an editable table

            shortParam=app.Data.trainingParam;

            excludestr=fieldnames(shortParam);

            if numel(app.Data.trainingParam.imageclassifier)==0
                excludestr=excludestr(contains(excludestr,{'freeze','rois','imageclassifier','roisID','classes'}));
            else
                excludestr=excludestr(contains(excludestr,{'freeze','rois','roisID','classes'}));
            end
            shortParam=rmfield(shortParam,excludestr);

            app.Data.shortParam=shortParam;

            % dat=struct2table(shortParam,'AsArray',true)
            fi=fieldnames(shortParam);
            Data={};
            for i=1:numel(fi)
                tmp=num2str(shortParam.(fi{i}));
                Data=[Data tmp];
                %   Data=[Data i];
            end

            t=app.UITrainingTable;

            t.Data=Data;
            t.ColumnName=fi;
            t.ColumnEditable=true;

            typeid=app.Data.classiObj.typeid;

            switch typeid
                case 4 % lstm+cnn image sequence
                    t.ColumnFormat={{'y','n'} {'y','n'} {'y','n'} {'y','n'} []  {'adam' 'sgdm'} {'resnet18' 'resnet50' 'googlenet'} [] [] [] {'every-epoch'} [] [] [] [] {'gpu','parallel','cpu'} [] []  [] []};
                case {2,8} % pixel classification
                    t.ColumnFormat={{'adam' 'sgdm'} {'resnet18' 'resnet50' 'googlenet'} [] [] [] {'every-epoch'} [] [] [] [] {'gpu','parallel','cpu'}};
                case 13
                    %  t.ColumnFormat={[] {'adam' 'sgdm'} {'resnet18' 'resnet50' 'googlenet'} [] [] [] {'every-epoch'} [] [] [] [] {'gpu','parallel','cpu'}};
            end
        end

        function displayData(app)
            rois=app.Data.classiObj.roi;
            trainingrois=app.Data.trainingParam.rois;

            % formatting roi table to be displayed :
            % columns :  selected for training , select for test, roi index, roi id ,
            % isannotated, isvalidated

            t=app.UITableData;

            Data={};

            if numel(rois)==1
                if numel(rois.id)==0
                    Data={};
                    t.Data={};
                    return;
                    % there is no roi available
                end
            end

            for i=1:numel(rois)

                istrainee=false;
                istest=true;
                isannotated=false;
                isvalidated=false;
                %  pix=find(trainingrois==i);
                if numel(find(trainingrois==i))>0

                    istrainee=true;
                    istest=false;
                end


                if app.Data.classiObj.typeid==2 || app.Data.classiObj.typeid==8 % pixel classification
                    % contains(rois(i).display.channel==app.Data.classiObj.strid)

                    t.ColumnName={'Select for training','Select for test',' ROI index','ROI Id'};
                    t.ColumnWidth={150, 150, 100, 'auto' };
                    t.ColumnEditable=[true true false false];
                    Data(i,:)={istrainee, istest, i, rois(i).id};

                else % all other classification

                    if isfield(rois(i).train,app.Data.classiObj.strid)
                        if numel(rois(i).train.(app.Data.classiObj.strid).id)
                        isannotated=true;
                        end
                    end
                    if isfield(rois(i).results,app.Data.classiObj.strid)
                       % 'ok'
                      
                        if numel(rois(i).results.(app.Data.classiObj.strid).prob)
                        isvalidated=true;
                        end
                    end
                    

                    t.ColumnName={'Select for training','Select for testing',' ROI index','ROI Id','is annotated','is validated'};
                    t.ColumnWidth={150, 150, 100, 'auto', 100, 100 };
                    t.ColumnEditable=[true true false false false false];
                    Data(i,:)={istrainee, istest, i, rois(i).id, isannotated, isvalidated};
                end

            end


            t.Data=Data;


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, classiObj)
            % HERE
            % app.callingApp=callingApp;
            % displayNodes(callingApp)
            %  gatherVarsFromWorkspace(callingApp)


            if nargin==1
                classiObj=classi;
                classiObj.path='';
                classiObj.strid='No input classi was provided';

            end

            app.UIFigure.Name=[classiObj.strid];
            app.Data.classiObj=classiObj;

            str=which('shallowNew.m');
            [pth file ext]=fileparts(str);
            str=[pth '/classification/classlist.mat'];
            load(str);
            app.Data.classlist=classlist;
            app.TypeDropDown.Items=classlist{:,2};
            app.Data.selected=[];
            
            if nargin>1
                displayClassi(app);
            end


            % loading training parameters
            if isfile([classiObj.path '/trainingParam.mat'])
                load([classiObj.path '/trainingParam.mat']) % loading trainingParam variable

                % disp(trainingParam);

            else

                disp('Training parameters do not exist: setting up defaults values!');

                switch classiObj.typeid
                    case {2,8}
                        trainingParam=struct('imageclassifier',[],'rois',1:numel(classiObj.roi),'method','adam','network','resnet50',...
                            'MiniBatchSize',8,'MaxEpochs',100,'InitialLearnRate',0.001,'Shuffle','every-epoch','split',0.9,...
                            'translateAugmentation',[-7 7],'rotateAugmentation',[-20 20],'regularization',0.005,'ExecutionEnvironment','gpu');
                    case 4
                        trainingParam=struct('imageclassifier','y','cactivations','y','lstmtraining','y','assemblenet','y','output',0,'rois',1:numel(classiObj.roi),'method','adam','network','googlenet',...
                            'MiniBatchSize',128,'MaxEpochs',6,'InitialLearnRate',0.0003,'Shuffle','every-epoch','split',0.7,...
                            'translateAugmentation',[-5 5],'rotateAugmentation',[-20 20],'regularization',0.00001,'ExecutionEnvironment','parallel',...
                            'lstmsplit',0.9,'lstmlayers',150,'lstmMiniBatchSize',8,'lstmInitialLearnRate', 0.0001);

                    case 13
                        trainingParam=struct('imageclassifier',[],'rois',1:numel(classiObj.roi),...
                            'lstmMiniBatchSize',8,'lstmInitialLearnRate',0.0001,'classes',classiObj.classes,'lstmsplit',0.7,'lstmlayers',150,'output',0);

                    otherwise
                        trainingParam=struct('imageclassifier',[],'rois',1:numel(classiObj.roi),'method','adam','network','resnet50',...
                            'MiniBatchSize',8,'MaxEpochs',100,'InitialLearnRate',0.001,'Shuffle','every-epoch','split',0.9,...
                            'translateAugmentation',[-7 7],'rotateAugmentation',[-20 20],'regularization',0.005,'ExecutionEnvironment','gpu');
                end

            end
            
            app.Data.trainingParam=trainingParam;


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: SaveclassifierparametersButton
        function SaveclassifierparametersButtonPushed(app, event)
            disp('Saving classifiers')
            
             d = uiprogressdlg(app.UIFigure,'Title','Please Wait...',...
                    'Message','Saving current classifier and trainingset...');
            d.Value=0.33;
            
            classiSave(app.Data.classiObj);

            d.Value=0.67;
            d.Message='Saving training parameters...';
            
            %a=app.Data.classiObj.path
           
            if numel(app.UITableData.Data)
            selectedfortraining=cellfun(@(x) x==1,app.UITableData.Data(:,1));
            app.Data.trainingParam.rois=find(selectedfortraining');
            else
             app.Data.trainingParam.rois =1:numel(app.Data.classiObj.roi);
            end
            %aaa=app.Data.trainingParam.rois
            
            
            if isfolder(app.Data.classiObj.path)
            mkdir([app.Data.classiObj.path '/TrainingValidation'])


            trainingParam=app.Data.trainingParam;
            save([app.Data.classiObj.path '/trainingParam.mat'],'trainingParam')
            save([app.Data.classiObj.path '/TrainingValidation' '/trainingParam.mat'],'trainingParam')

            d.Value=1;
            d.Message='Classifier is saved !';
            else
            d.Value=1;
            d.Message='Could not find/access the folder to save parameters; Check your connection? Quitting!';    
            pause(1);
            end
            pause(0.5);
            close(d)
        end

        % Button down function: TrainingprocedureparametersTab
        function TrainingprocedureparametersTabButtonDown(app, event)
            displayTraining(app)

        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.Value;
            pix=find(contains(app.TypeDropDown.Items,value));
            app.Data.classiObj.typeid=pix;
            
             classlist=app.Data.classlist;
             
            app.Data.classiObj.description=[ classlist{pix,2} ' - ' classlist{pix,3}];
            
            app.Data.classiObj.classifyFun=classlist{pix,6}{1};
            app.Data.classiObj.trainingFun=classlist{pix,5}{1};
            
           % aa=classlist{pix,3}
            
            displayClassi(app);
        end

        % Value changed function: ClassifieroutputtypeDropDown
        function ClassifieroutputtypeDropDownValueChanged(app, event)
            value = app.ClassifieroutputtypeDropDown.Value;
            pix=find(contains(app.ClassifieroutputtypeDropDown.Items,value));
            
            app.Data.classiObj.output=pix-1;


            app.Data.trainingParam.output=pix-1;
            displayClassi(app);
        end

        % Value changed function: 
        % ChannelindexindicesforimageclassificationEditField
        function ChannelindexindicesforimageclassificationEditFieldValueChanged(app, event)
            value = app.ChannelindexindicesforimageclassificationEditField.Value;
            app.Data.classiObj.channel=str2num(value);
        end

        % Value changed function: SpaceseparatedclassnamesEditField
        function SpaceseparatedclassnamesEditFieldValueChanged(app, event)
            value = app.SpaceseparatedclassnamesEditField.Value;
            classes = textscan(value,'%s','Delimiter',' ');
            app.Data.classiObj.classes=classes{1};
        end

        % Callback function
        function PostprocessingfunctionhandleEditFieldValueChanged(app, event)
            value =PostprocessingfunctionhandleEditFieldValueChanged.Value;
            app.Data.classiObj.outputFun=str2func(value);
        end

        % Value changed function: PostprocessingDropDown
        function PostprocessingDropDownValueChanged(app, event)
            value = app.PostprocessingDropDown.Value;
            
            if strcmp(value,'None')
                value='';
            end
            
            app.Data.classiObj.outputType=value;
            displayClassi(app);
        end

        % Value changed function: 
        % PostprocessingfunctionhandleEditField
        function PostprocessingfunctionhandleEditFieldValueChanged2(app, event)
            value = app.PostprocessingfunctionhandleEditField.Value;
            app.Data.classiObj.outputFun=value;
        end

        % Cell edit callback: UITrainingTable
        function UITrainingTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            par=app.UITrainingTable.ColumnName{indices(2)};

            if numel(str2num(newData)) % convert data if they are numeric
                app.Data.trainingParam.(par)=str2num(newData);
            else
                app.Data.trainingParam.(par)=newData;
            end

            if strcmp(par,'output')
                app.Data.classiOj.output=str2num(newData);
            end
            displayTraining(app);
        end

        % Button down function: DatasetsROIsTab
        function DatasetsROIsTabButtonDown(app, event)
            displayData(app);
        end

        % Cell edit callback: UITableData
        function UITableDataCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            if indices(2)==2
                app.UITableData.Data{indices(1),1}=~app.UITableData.Data{indices(1),1};
            end
            if indices(2)==1
                app.UITableData.Data{indices(1),2}=~app.UITableData.Data{indices(1),2};
            end

            selectedfortraining=cellfun(@(x) x==1,app.UITableData.Data(:,1));
            app.Data.trainingParam.rois=find(selectedfortraining');
            
            displayData(app);
        end

        % Button pushed function: AnnotateselectedROIButton
        function AnnotateselectedROIButtonPushed(app, event)
            sel=app.Data.selected;
            if numel(sel)==0
                uialert(app.UIFigure,'First select an ROI!','Error')
                return;
            end
            disp(['Launching ROI #' num2str(sel) '.... Please wait...']);
            app.Data.classiObj.userTraining('Roi',sel);
        end

        % Cell selection callback: UITableData
        function UITableDataCellSelection(app, event)
            indices = event.Indices;
            app.Data.selected=indices(1);
        end

        % Button pushed function: ImportROIsButton
        function ImportROIsButtonPushed(app, event)
            
            
            roiImporterGUI(app)
            uiwait(app.UIFigure);
            
            displayData(app);
        end

        % Button pushed function: FormattrainingsetfortrainingButton
        function FormattrainingsetfortrainingButtonPushed(app, event)
           d = uiprogressdlg(app.UIFigure,'Title','Please Wait...',...
                    'Message','Exporting trainingset to files; PLease wait...');
            d.Value=0.33;
           
            output=app.Data.classiObj.formatDataForTraining;
            
            d.Value=0.66; 
            
            if numel(output)>0
            d.Message=[num2str(output) ' files/images were exported; The classifier is reday to be trained!'];
            else
            d.Message=['No file was exported; Check your trainingset!'];
            pause(1)
            end
            
            pause(2);
            close(d)
        end

        % Button pushed function: SelectallButton
        function SelectallButtonPushed(app, event)
            for i=1:size(app.UITableData.Data,1)
            app.UITableData.Data{i,1}=true;
            end
            
            selectedfortraining=cellfun(@(x) x==1,app.UITableData.Data(:,1));
            app.Data.trainingParam.rois=find(selectedfortraining');
            displayData(app);
        end

        % Button pushed function: DeselectallButton
        function DeselectallButtonPushed(app, event)
            for i=1:size(app.UITableData.Data,1)
            app.UITableData.Data{i,1}=false;
            end
            
            selectedfortraining=cellfun(@(x) x==1,app.UITableData.Data(:,1));
            app.Data.trainingParam.rois=find(selectedfortraining');
            displayData(app);
        end

        % Button pushed function: removeselectedROIButton
        function removeselectedROIButtonPushed(app, event)
     
            sel=app.Data.selected;
            if numel(sel)
             if numel(app.Data.classiObj.roi)==1
                 app.Data.classiObj.roi=roi;
             else
                 
                pix=setxor(1:numel(app.Data.classiObj.roi),sel); 
                app.Data.classiObj.roi=app.Data.classiObj.roi(pix);
             end
            end

 displayData(app);

        end

        % Button pushed function: TrainclassifierButton
        function TrainclassifierButtonPushed(app, event)
            
           classiObj=app.Data.classiObj;
           
           if ~exist(fullfile(classiObj.path,'trainingParam.mat'))
               
               uialert(app.UIFigure,'Error','Training parameters were not saved !')
               
           end
           
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait...',...
                    'Message','Training network; Please wait...');
            d.Value=0.5;

            classiObj.trainClassifier;
           
            d.Value=1;
            
            pause(1);
            close(d) 


        end

        % Button pushed function: ValidateclassifierButton
        function ValidateclassifierButtonPushed(app, event)
             
             classiObj=app.Data.classiObj;
           
           if ~exist(fullfile(classiObj.path,'trainingParam.mat'))
               
               uialert(app.UIFigure,'Error','Training parameters were not saved !')
               
           end
           
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait...',...
                    'Message','Checking network classifier performance using groundtruth data; Please wait...');
            d.Value=0.5;

            classiObj.validateTrainingData;
           
            d.Value=1;
            
            pause(1);
            close(d) 
            
        end

        % Button pushed function: DisplaystatisticsButton
        function DisplaystatisticsButtonPushed(app, event)
            classiObj=app.Data.classiObj;
            aa=app.UITableData.Data;
            
            if numel(aa)==0
                 uialert(app.UIFigure,'First select the testset in the dataset tab or make sure you have ROIs!','Error');
                 return;
            end
           
            
            % gather the test set. 
            
            selectedfortest=find(cellfun(@(x) x==0,app.UITableData.Data(:,1)));
            classiObj.stats('Confusion','Classes','Rois',selectedfortest','Force');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SaveclassifierparametersButton = uibutton(app.UIFigure, 'push');
            app.SaveclassifierparametersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveclassifierparametersButtonPushed, true);
            app.SaveclassifierparametersButton.Text = 'Save classifier & parameters';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ClassifierparametersTab = uitab(app.TabGroup);
            app.ClassifierparametersTab.Title = 'Classifier parameters';
            app.TypeLabel = uilabel(app.ClassifierparametersTab);
            app.TypeLabel.Text = 'Type:';
            app.TypeDropDown = uidropdown(app.ClassifierparametersTab);
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.ClassifieroutputtypeDropDownLabel = uilabel(app.ClassifierparametersTab);
            app.ClassifieroutputtypeDropDownLabel.Text = 'Classifier output type:';
            app.ClassifieroutputtypeDropDown = uidropdown(app.ClassifierparametersTab);
            app.ClassifieroutputtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ClassifieroutputtypeDropDownValueChanged, true);
            app.SpaceseparatedclassnamesLabel = uilabel(app.ClassifierparametersTab);
            app.SpaceseparatedclassnamesLabel.Text = 'Space-separated class names: ';
            app.SpaceseparatedclassnamesEditField = uieditfield(app.ClassifierparametersTab, 'text');
            app.SpaceseparatedclassnamesEditField.ValueChangedFcn = createCallbackFcn(app, @SpaceseparatedclassnamesEditFieldValueChanged, true);
            app.ChannelindexindicesforimageclassificationEditFieldLabel = uilabel(app.ClassifierparametersTab);
            app.ChannelindexindicesforimageclassificationEditFieldLabel.Text = 'Channel index (indices) for image classification:';
            app.ChannelindexindicesforimageclassificationEditField = uieditfield(app.ClassifierparametersTab, 'text');
            app.ChannelindexindicesforimageclassificationEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelindexindicesforimageclassificationEditFieldValueChanged, true);
            app.PostprocessingfunctionhandleEditFieldLabel = uilabel(app.ClassifierparametersTab);
            app.PostprocessingfunctionhandleEditFieldLabel.Text = 'Post-processing function handle:';
            app.PostprocessingfunctionhandleEditField = uieditfield(app.ClassifierparametersTab, 'text');
            app.PostprocessingfunctionhandleEditField.ValueChangedFcn = createCallbackFcn(app, @PostprocessingfunctionhandleEditFieldValueChanged2, true);
            app.ClassiDetailsLabel = uilabel(app.ClassifierparametersTab);
            app.ClassiDetailsLabel.Text = 'ClassiDetails';
            app.PostprocessingDropDownLabel = uilabel(app.ClassifierparametersTab);
            app.PostprocessingDropDownLabel.Text = 'Post-processing';
            app.PostprocessingDropDown = uidropdown(app.ClassifierparametersTab);
            app.PostprocessingDropDown.Items = {'proba', 'segmentation', 'postprocessing', ''};
            app.PostprocessingDropDown.ValueChangedFcn = createCallbackFcn(app, @PostprocessingDropDownValueChanged, true);
            app.PostprocessingDropDown.Value = 'proba';
            app.TrainingprocedureparametersTab = uitab(app.TabGroup);
            app.TrainingprocedureparametersTab.Title = 'Training procedure parameters';
            app.TrainingprocedureparametersTab.ButtonDownFcn = createCallbackFcn(app, @TrainingprocedureparametersTabButtonDown, true);
            app.UITrainingTable = uitable(app.TrainingprocedureparametersTab);
            app.UITrainingTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITrainingTable.RowName = {};
            app.UITrainingTable.CellEditCallback = createCallbackFcn(app, @UITrainingTableCellEdit, true);
            app.DatasetsROIsTab = uitab(app.TabGroup);
            app.DatasetsROIsTab.Title = 'Datasets (ROIs)';
            app.DatasetsROIsTab.ButtonDownFcn = createCallbackFcn(app, @DatasetsROIsTabButtonDown, true);
            app.DatasetsROIsTab.Tag = 'list';
            app.UITableData = uitable(app.DatasetsROIsTab);
            app.UITableData.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableData.RowName = {};
            app.UITableData.CellEditCallback = createCallbackFcn(app, @UITableDataCellEdit, true);
            app.UITableData.CellSelectionCallback = createCallbackFcn(app, @UITableDataCellSelection, true);
            app.ImportROIsButton = uibutton(app.DatasetsROIsTab, 'push');
            app.ImportROIsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportROIsButtonPushed, true);
            app.ImportROIsButton.Text = 'Import ROIs...';
            app.AnnotateselectedROIButton = uibutton(app.DatasetsROIsTab, 'push');
            app.AnnotateselectedROIButton.ButtonPushedFcn = createCallbackFcn(app, @AnnotateselectedROIButtonPushed, true);
            app.AnnotateselectedROIButton.Text = 'Annotate selected ROI...';
            app.SelectallButton = uibutton(app.DatasetsROIsTab, 'push');
            app.SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectallButtonPushed, true);
            app.SelectallButton.Text = 'Select all';
            app.DeselectallButton = uibutton(app.DatasetsROIsTab, 'push');
            app.DeselectallButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectallButtonPushed, true);
            app.DeselectallButton.Text = 'Deselect all';
            app.removeselectedROIButton = uibutton(app.DatasetsROIsTab, 'push');
            app.removeselectedROIButton.ButtonPushedFcn = createCallbackFcn(app, @removeselectedROIButtonPushed, true);
            app.removeselectedROIButton.Text = 'remove selected ROI';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TrainclassifierButton = uibutton(app.UIFigure, 'push');
            app.TrainclassifierButton.ButtonPushedFcn = createCallbackFcn(app, @TrainclassifierButtonPushed, true);
            app.TrainclassifierButton.Text = 'Train classifier';
            app.FormattrainingsetfortrainingButton = uibutton(app.UIFigure, 'push');
            app.FormattrainingsetfortrainingButton.ButtonPushedFcn = createCallbackFcn(app, @FormattrainingsetfortrainingButtonPushed, true);
            app.FormattrainingsetfortrainingButton.Text = 'Format training set for training';
            app.ValidateclassifierButton = uibutton(app.UIFigure, 'push');
            app.ValidateclassifierButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateclassifierButtonPushed, true);
            app.ValidateclassifierButton.Tooltip = {'This applies the classifier to the whole data set (training + test)'};
            app.ValidateclassifierButton.Text = 'Validate classifier';
            app.DisplaystatisticsButton = uibutton(app.UIFigure, 'push');
            app.DisplaystatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaystatisticsButtonPushed, true);
            app.DisplaystatisticsButton.Tooltip = {'Benchmarks are computed for the test set only. Make sure that some ROIs have been kept for testing !'};
            app.DisplaystatisticsButton.Text = 'Display statistics';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '>';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '>';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '>';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '>';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = classifierGUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end