classdef classifyDataGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClassifydataUIFigure          matlab.ui.Figure
        CloseButton                   matlab.ui.control.Button
        ClassifyselecteddataButton    matlab.ui.control.Button
        SelectpositionsandenterROIsarrayLabel  matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        SelectclassfiermodelDropDown  matlab.ui.control.DropDown
        SelectclassfiermodelDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Data % Description
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, shallowObj)
            app.Data.shallowObj=shallowObj;
            
            str={};
            for i=1:numel(shallowObj.processing.classification)
                str{i}=shallowObj.processing.classification(i).strid;
            end
            app.SelectclassfiermodelDropDown.Items=str;
            
            t=app.UITable;
            t.ColumnName={'Select','Position','Position Name','Select channel to classify','Select ROIs array'};
            t.ColumnEditable=[true false false true true];
            
            Data={};
            
            for i=1:numel(shallowObj.fov)
                Data(i,:)={true num2str(i) shallowObj.fov(i).id num2str(shallowObj.processing.classification(1).channel) ['1:' num2str(numel(shallowObj.fov(i).roi))]};
            end
            t.Data=Data;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end

        % Close request function: ClassifydataUIFigure
        function ClassifydataUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: SelectclassfiermodelDropDown
        function SelectclassfiermodelDropDownValueChanged(app, event)
            value = app.SelectclassfiermodelDropDown.Value;
            
            shallowObj=app.Data.shallowObj;
            
            pix=find(contains(app.SelectclassfiermodelDropDown.Items,value));
            
            dat=app.UITable.Data;
            
            dat{:,4}=num2str(shallowObj.processing.classification(pix).channel);
            
            app.UITable.Data=dat;
        end

        % Button pushed function: ClassifyselecteddataButton
        function ClassifyselecteddataButtonPushed(app, event)
             shallowObj=app.Data.shallowObj;
            
             list=app.SelectclassfiermodelDropDown.Items;
             valueClassi=find(contains(list,app.SelectclassfiermodelDropDown.Value));
             
             data=app.UITable.Data;
             
             selpos=find(cellfun(@(x) x==1,data(:,1)));
             
             roiscell={};
             channel=[];
             
             for i=1:numel(selpos)
             roiscell{i}=str2num(data{selpos(i),5});
             channel(i)=str2num(data{selpos(i),4});
             end

           %  channel
          

            % roiscell
             d = uiprogressdlg(app.ClassifydataUIFigure,'Title','Please Wait...',...
                    'Message','Starting data classification...');
            d.Value=0.01;
            
   
             shallowObj.classifyData(valueClassi,'Fovs',selpos,'Rois',roiscell,'Progress',d,'Channel',channel);
             
           %  uialert(app.ClassifydataUIFigure,'Don t forget to save the project!!','Warning');
             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ClassifydataUIFigure = uifigure('Visible', 'off');
            app.ClassifydataUIFigure.Name = 'Classify data';
            app.ClassifydataUIFigure.CloseRequestFcn = createCallbackFcn(app, @ClassifydataUIFigureCloseRequest, true);
            app.SelectclassfiermodelDropDownLabel = uilabel(app.ClassifydataUIFigure);
            app.SelectclassfiermodelDropDownLabel.Text = 'Select classfier/model: ';
            app.SelectclassfiermodelDropDown = uidropdown(app.ClassifydataUIFigure);
            app.SelectclassfiermodelDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectclassfiermodelDropDownValueChanged, true);
            app.UITable = uitable(app.ClassifydataUIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.SelectpositionsandenterROIsarrayLabel = uilabel(app.ClassifydataUIFigure);
            app.SelectpositionsandenterROIsarrayLabel.Text = 'Select positions and enter ROIs array:';
            app.ClassifyselecteddataButton = uibutton(app.ClassifydataUIFigure, 'push');
            app.ClassifyselecteddataButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyselecteddataButtonPushed, true);
            app.ClassifyselecteddataButton.Text = 'Classify selected data';
            app.CloseButton = uibutton(app.ClassifydataUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.ClassifydataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = classifyDataGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ClassifydataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClassifydataUIFigure)
        end
    end
end