classdef detecdiv < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DetecDivUIFigure            matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        NewprojectMenu              matlab.ui.container.Menu
        OpenprojectMenu             matlab.ui.container.Menu
        SaveselectedprojectMenu     matlab.ui.container.Menu
        Closeproject                matlab.ui.container.Menu
        NewclassiiferMenu           matlab.ui.container.Menu
        OpenclassifierMenu          matlab.ui.container.Menu
        HelpMenu                    matlab.ui.container.Menu
        ProjectsPanel               matlab.ui.container.Panel
        ProjectInformationLabel     matlab.ui.control.Label
        AddclassifierButton         matlab.ui.control.Button
        AddDataButton               matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        Tree                        matlab.ui.container.Tree
        ProjectsNode                matlab.ui.container.TreeNode
        IndependentClassifiersNode  matlab.ui.container.TreeNode
    end

    
    properties (Access = private)
        Data % Description
    end
    
    methods (Access = private)
        
        function displayNodes(app)
            cc=1;
            %h1=[];
            %hproj=[];
            %hclassi=[];
               

           t=app.ProjectsNode.Children;
           t.delete;
           t=app.IndependentClassifiersNode.Children;
           t.delete;
           
             [pth fle ext]= fileparts(which('detecdiv.mlapp'));
             
            for i=1:numel(app.Data.Project)
            h1(i)=uitreenode(app.ProjectsNode,'Text',app.Data.Project{i},'Tag','Project','UserData',i,'Icon',fullfile(pth,'detecDiv_logo.png'));
            
            
            for k=1:numel(app.Data.Projectclassi{i})
             cm=uicontextmenu(app.DetecDivUIFigure);
             m = uimenu(cm,'Text','Open classifier...');
             m.MenuSelectedFcn={@contextMenuFcn,[i,k],'Projectclassi'};
            
            uitreenode(h1(i),'Text',app.Data.Projectclassi{i}{k},'Tag','Projectclassi','UserData',[i,k],'ContextMenu',cm,'Icon',fullfile(pth,'brain.png'));
            end
            
            for k=1:numel(app.Data.Projectpos{i})
             cm=uicontextmenu(app.DetecDivUIFigure);
             m = uimenu(cm,'Text','Open project...');
             m.MenuSelectedFcn={@contextMenuFcn,[i,k],'Projectpos'};
            uitreenode(h1(i),'Text',app.Data.Projectpos{i}{k},'Tag','Projectpos','UserData',[i,k],'ContextMenu',cm,'Icon',fullfile(pth,'data.png'));
            end 
            end
    
            for i=1:numel(app.Data.Classifier) 
             cm=uicontextmenu(app.DetecDivUIFigure);
             m = uimenu(cm,'Text','Open classifier...');
             m.MenuSelectedFcn={@contextMenuFcn,i,'CLassifier'};
            uitreenode(app.IndependentClassifiersNode,'Text',app.Data.Classifier{i},'Tag','Classifier','UserData',i,'ContextMenu',cm,'Icon',fullfile(pth,'brain.png'));
            end
 
            expand(app.ProjectsNode);
            expand(app.IndependentClassifiersNode);
            
            function contextMenuFcn(src,event,arg,str)
          
              str,arg
        %    i,k
            end
 
        end


        function gatherVarsFromWorkspace(app)
            varlist=evalin('base','who');
            st=struct('Project',{''},'Classifier',{''},'Projectpos',{''},'Projectclassi',{''});
            cc=0;
            app.Data={};
            
            for i=1:numel(varlist)
                
                if strcmp(varlist{i},'ans')
                        continue;
                end
                
                 tmp=evalin('base',varlist{i});
               
                 
                if isa(tmp,'shallow')
                    disp('this is a shallow object')
                    cc=cc+1;
                    
                    st.Project{cc}=varlist{i};
                    
                    tmpclassi={};
                    
                    for k=1:numel(tmp.processing.classification)
                      %  k
                       tmpclassi = [tmpclassi tmp.processing.classification(k).strid];  
                    end
                    
                    st.Projectclassi{cc}=tmpclassi;
                    
                     tmpproj={};
                    
                    for k=1:numel(tmp.fov)
                      %  k
                       tmpproj = [tmpproj tmp.fov(k).id]; 
                    end
                    
                    st.Projectpos{cc}=tmpproj;
                end
                
                 if isa(tmp,'classi')
                    disp('this is a classification object')
                    cc=cc+1;
                    st.Classifier{cc}=varlist{i};
                end
                
            end
            
            app.Data=st;
        end
        
        function results = getSpecificWorkspaceVar(varstr)
            results=evalin('base',varstr);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            gatherVarsFromWorkspace(app)
            displayNodes(app)
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            %app.selectedNode=selectedNodes.Tag
        end

        % Callback function
        function ContextMenuOpening(app, event)
      
        end

        % Callback function
        function OpenMenuSelected(app, event)
 
        end

        % Menu selected function: NewprojectMenu
        function NewprojectMenuSelected(app, event)
            proj=shallowNew;
            if numel(proj)==0
                return;
            end
           name=proj.io.file;
           assignin('base',name,proj);
           gatherVarsFromWorkspace(app);
           displayNodes(app)
        end

        % Callback function
        function DetecDivUIFigureWindowButtonDown(app, event)
          
        end

        % Callback function
        function DetecDivUIFigureButtonDown(app, event)
            
        end

        % Menu selected function: OpenprojectMenu
        function OpenprojectMenuSelected(app, event)
            [proj msg]=shallowLoad;
            if numel(proj)==0
                            if numel(msg)
                            uialert(app.DetecDivUIFigure,msg,'Warning','Icon','warning');
                            end
                return;
            end

           name=proj.io.file;
           assignin('base',name,proj);
           gatherVarFromWorkspace(app);
           displayNodes(app)
        end

        % Menu selected function: Closeproject
        function CloseprojectMenuSelected(app, event)

            if strcmp(app.Tree.SelectedNodes.Tag,'Project')
                i=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Project{i};
                
                evalin('base',['clear ' proj]);
                gatherVarsFromWorkspace(app);
                displayNodes(app)
            end
        end

        % Menu selected function: SaveselectedprojectMenu
        function SaveselectedprojectMenuSelected(app, event)
             if strcmp(app.Tree.SelectedNodes.Tag,'Project')
                i=app.Tree.SelectedNodes.UserData;
                proj=app.Data.Project{i};
                
                shallowObj=evalin('base',proj);
                
                shallowSave(shallowObj,[],progress);

            end
             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DetecDivUIFigure = uifigure('Visible', 'off');
            app.DetecDivUIFigure.Name = 'DetecDiv';
            app.DetecDivUIFigure.Icon = 'detecDiv_logo.png';
            app.FileMenu = uimenu(app.DetecDivUIFigure);
            app.FileMenu.Text = 'File';
            app.NewprojectMenu = uimenu(app.FileMenu);
            app.NewprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @NewprojectMenuSelected, true);
            app.NewprojectMenu.Text = 'New project...';
            app.OpenprojectMenu = uimenu(app.FileMenu);
            app.OpenprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenprojectMenuSelected, true);
            app.OpenprojectMenu.Text = 'Open project...';
            app.SaveselectedprojectMenu = uimenu(app.FileMenu);
            app.SaveselectedprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveselectedprojectMenuSelected, true);
            app.SaveselectedprojectMenu.Text = 'Save selected project';
            app.Closeproject = uimenu(app.FileMenu);
            app.Closeproject.MenuSelectedFcn = createCallbackFcn(app, @CloseprojectMenuSelected, true);
            app.Closeproject.Text = 'Close selected project';
            app.NewclassiiferMenu = uimenu(app.FileMenu);
            app.NewclassiiferMenu.Separator = 'on';
            app.NewclassiiferMenu.Text = 'New classiifer...';
            app.OpenclassifierMenu = uimenu(app.FileMenu);
            app.OpenclassifierMenu.Text = 'Open classifier...';
            app.HelpMenu = uimenu(app.DetecDivUIFigure);
            app.HelpMenu.Text = 'Help';
            app.Tree = uitree(app.DetecDivUIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.Tooltip = {'Use left-click to scroll projects and classifiers; Use right-click to open positions and classifiers'};
            app.ProjectsNode = uitreenode(app.Tree);
            app.ProjectsNode.Text = 'Projects';
            app.IndependentClassifiersNode = uitreenode(app.Tree);
            app.IndependentClassifiersNode.Text = 'Independent Classifiers';
            app.ProjectsPanel = uipanel(app.DetecDivUIFigure);
            app.ProjectsPanel.Title = 'Projects';
            app.SaveButton = uibutton(app.ProjectsPanel, 'push');
            app.SaveButton.Text = 'Save';
            app.AddDataButton = uibutton(app.ProjectsPanel, 'push');
            app.AddDataButton.Text = 'AddData...';
            app.AddclassifierButton = uibutton(app.ProjectsPanel, 'push');
            app.AddclassifierButton.Text = 'Add classifier...';
            app.ProjectInformationLabel = uilabel(app.ProjectsPanel);
            app.ProjectInformationLabel.Text = 'Project Information';
            % Show the figure after all components are created
            app.DetecDivUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = detecdiv

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DetecDivUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.DetecDivUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DetecDivUIFigure)
        end
    end
end