classdef detector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DivisioncountingandRLSanalysisUIFigure  matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        LoaddatagroupsMenu         matlab.ui.container.Menu
        SavedatagroupsasMenu       matlab.ui.container.Menu
        ExportaveragedataButton    matlab.ui.control.Button
        ExportplotsButton          matlab.ui.control.Button
        PlotButton                 matlab.ui.control.Button
        OptionsPanel               matlab.ui.container.Panel
        DatasourcePanel            matlab.ui.container.Panel
        Lamp                       matlab.ui.control.Lamp
        SaveselectionButton        matlab.ui.control.Button
        ROIselectionPanel          matlab.ui.container.Panel
        DatagroupsPanel            matlab.ui.container.Panel
        AssignselectedgroupparamtoallButton  matlab.ui.control.Button
        RenamegroupEditField       matlab.ui.control.EditField
        RenamegroupEditFieldLabel  matlab.ui.control.Label
        DeleteselectedgroupButton  matlab.ui.control.Button
        NewgroupButton             matlab.ui.control.Button
        DatagroupsListBox          matlab.ui.control.ListBox
    end


    properties (Access = private)
        Data % Description
    end

    methods (Access = private)
        function  gatherVarsFromWorkspace(app)

            varlist=evalin('base','who');
            st=struct('Project',{''},'Classifier',{''},'Projectpos',{''},'Projectclassi',{''});
            stclassi=struct('Classifier',{[]},'Projectclassi',{[]});

            cc=0;
            cd=0;
            app.Data.st=[];
            app.Data.stclassi=[];

            for i=1:numel(varlist)

                if strcmp(varlist{i},'ans')
                    continue;
                end

                tmp=evalin('base',varlist{i});

                if isa(tmp,'shallow')
                    disp('this is a shallow object')
                    cc=cc+1;

                    st.Project{cc}=varlist{i};

                    tmpclassi={};
                    tmpclassi2=classi;

                    for k=1:numel(tmp.processing.classification)
                        tmpclassi = [tmpclassi tmp.processing.classification(k).strid];

                        tmpclassi2(k)= tmp.processing.classification(k);
                    end


                    st.Projectclassi{cc}=tmpclassi;
                    stclassi.Projectclassi{cc}=tmpclassi2;

                    tmpproj={};

                    for k=1:numel(tmp.fov)
                        %  k
                        tmpproj = [tmpproj tmp.fov(k).id];
                    end

                    st.Projectpos{cc}=tmpproj;
                end

                if isa(tmp,'classi')

                    disp('this is a classification object')
                    cd=cd+1;
                    st.Classifier{cd}=varlist{i};

                    stclassi.Classifier{cd}=evalin('base',varlist{i});
                    % aa= st.Classifier{cd}
                end

            end

            %  st
            app.Data.st=st;
            app.Data.stclassi=stclassi;
            %    app.Data.convert={};
        end

        function divparam= newDivPlot(app)
            tip={'Set filename to be written in the current directory',...
                'Set the frame interval (min)',...
                'Set the last birth event (frames)',...
                'Set the bin size (minutes)',...
                'Set the max bin for the histogram (minutes)'};

            divparam=struct( 'Filename','myDivfile',...
                'FrameInterval',5,...
                'maxBirth',100,...
                'binSize',5,...
                'maxBin',200,...
                'tip',{tip});
            %       'GroundtruthVSPredictions',false,...
            %     'AlignTraj',{{'Birth','SEP','Death','Birth'}},...

        end

        function param = newGroup(app)
            tip={'Select the classifier used to score divisions',...
                'Choose the type of division classification (bud or div) ',...
                'Perform postprocessing or not',...
                'Detect errors or not',...
                'Lifespan arrest threshold( frames)',...
                'Number of consecutive dead frames before death is defined as the end of the RLS',...
                'Number of consecutive clog frames before clog is defined as the end of the RLS',...
                'Number of frames before traps is declared empty',...
                '?',...
                'Frames array used for analysis (set -1 if all frames should be considered)',...
                'Check this box if this group should be plotted',...
                'Check this box if predictions and GT should be compared'};
            varlist=app.Data.st;
            varlistclassi=app.Data.stclassi;
            classilist={};
            % classiobj=classi;
            cc=1;
            classiobj={};


            for i=1:numel(varlist.Projectclassi)
                for j=1:numel(varlist.Projectclassi{i})
                    classilist{cc}=varlist.Projectclassi{i}{j};
                    tmp=varlistclassi.Projectclassi{i}
                    classiobj{cc}=tmp(j);
                    %  tmp=varlist.Projectclassi{i}{j}
                    cc=cc+1;
                end
            end

            for i=1:numel(varlist.Classifier)
                classilist{cc}=varlist.Classifier{i};
                classiobj{cc}=varlistclassi.Classifier{i};
                cc=cc+1;
            end


            app.Data.classiobj=classiobj;
            if numel(classilist)
            classilist{end+1}=classilist{1};
            else
             classilist={'No Classi in this project','No Classi in this project'}   ;
            end

            param=struct('classifierName',{classilist},...
                'classiftype',{{'bud','div','bud'}},...
                'postProcessing',true,...
                'errorDetection',true,...
                'ArrestThreshold',175,...
                'DeathThreshold',3,...
                'ClogThreshold',1,...
                'EmptyThresholdDiscard',500,...
                'EmptyThresholdNext',100,...
                'Frames',[-1],...
                'PlotThisGroup',true,...
                'PredVSGT',false,...
                'tip',{tip});
            %   'GroundtruthVSPredictions',false,...
            %     'AlignTraj',{{'Birth','SEP','Death','Birth'}},...
        end

        function divparam = newRLSPlot(app)
            tip={'Set filename to be written in the current directory',...
                'Plot 95% confidence interval for survival',...
                'Plot Hazard rate',...
                'If plot hazard rate, which binning to use (higher binning decreases noise but reduces temporal resolution)',...
                'If plot hazard rate, stop displaying the curve when less than this number of cells are still alive (avoid interpretations based on very few cells). !!For the moment, only works if bootstrap is ticked!!',...
                'Set the frame interval (min)',...
                'Set the last birth event (frames)',...
                'Check to bootstrap data (error estimate)',...
                'Number of boostrap steps'};

            divparam=struct('Filename','myRLSfile',...
                'ConfidenceInterval',true,...
                'HazardRate',false,...
                'Binning_HazardRate',4,...
                'MinNumberCell_HazardRate',10,...
                'FrameInterval',5,...
                'maxBirth',100,...
                'Bootstrap',false,...
                'Bootstrap_Steps',200,...
                'tip',{tip});
            %       'GroundtruthVSPredictions',false,...
            %     'AlignTraj',{{'Birth','SEP','Death','Birth'}},...
        end
        
        function signalparam = newSignalPlot(app)
              tip={'Set filename to be written in the current directory',...
                'Choose whether data use generation or physical time units',...
                'Set the frame interval (min)',...
                'Set the last birth event (frames)',...
                'Check to bootstrap data (error estimate)',...
                'Number of boostrap steps',...
                'Align trajectories from birth, SEP, or death'};

            signalparam=struct('Filename','mySignalfile',...
                'timeOrGen',{{'Time','Generation','Time'}},...
                'FrameInterval',5,...
                'maxBirth',100,...
                'minDiv',1,...
                'minSep',0,...
                'Align_Traj',{{'birth','SEP','death','birth'}},...
                'tip',{tip});
        end
    end


    methods (Access = public)
        function display2(app,datapicker)

            d = uiprogressdlg(app.DivisioncountingandRLSanalysisUIFigure,'Title','Please Wait...',...
                'Message','Loading data group...');
            d.Value=0.33;

            groups = app.Data.groups;
            items= {groups.Name};

            if numel(app.DatagroupsListBox.Value)
                aa=app.DatagroupsListBox.Value;
                pix=find(matches(items,aa));

                if numel(pix)==0
                    pix=1;
                else
                    pix=pix(1);
                end
            else
                pix=1;
            end

            app.DatagroupsListBox.Items=items;
            app.DatagroupsListBox.Value=items{pix};

            %                    app.Data.selectedgroup=pix;
            %                    pix=find(matches(app.DatagroupsListBox.Items,app.DatagroupsListBox.Value));
            param=app.Data.groups(pix).Param;

            %here
            
            tip=param.tip;
            param=rmfield(param,'tip');
            app.DivisionextractionparametersPanel.UserData=[];
            delete(app.DivisionextractionparametersPanel.Children);
            struct2GUI(param,[10 330],'Handle',app.DivisionextractionparametersPanel,'Tip',tip,'Flag',app.Lamp);
            app.DivisionextractionparametersPanel.UserData.tip=tip;
            app.RenamegroupEditField.Value=aa;

            if nargin==2 % creates datapicker
                if numel(app.Data.groups(pix).Data.nodeid)
                    nodes=app.Data.groups(pix).Data;
                    nodes=app.Data.groups(pix).Data.nodeid;
                else
                    nodes=[];
                end
                %  no=app.Data.groups(pix).Data.filepath{1}


                if isa('datatree','var')
                    if ishandle(datatree)
                        delete(datatree)
                    end
                end
                d.Value=0.5;
                d.Message='Refreshing data picker...';

                [datatree,~]=datapickerGUI2('Handle',app.ROIselectionPanel,'Flag',app.Lamp,'Position',[12 12 240 350],'Input',nodes);
                app.Data.tree=datatree;
                %  tmp=app.Data.tree.UserData
                drawnow


            end

            % display rls table if avaialable

%             t=app.UITable;
%             t.ColumnWidth={50, 100,  'auto', 50,50,70,35,50,50};
%             t.ColumnEditable=[true false false false false false false false];
% 
%             if isfield(app.Data.groups(pix).Data,'rls')
%                 if numel(app.Data.groups(pix).Data.rls)
% 
%                     rls=app.Data.groups(pix).Data.rls;
%                     if numel(app.Data.groups(pix).Table)
%                         t.Data=app.Data.groups(pix).Table;
%                    %     'ok'
%                     else
% 
% 
%                         Data=t.Data;
% 
%                         for i=1:numel(rls)
%                             Data{i,1}=true;
%                             Data{i,2}=rls(i).name;
%                             Data{i,3}=num2str(rls(i).divDuration);
%                             Data{i,4}=rls(i).ndiv;
%                             Data{i,5}=rls(i).sep;
%                             Data{i,6}=rls(i).endType;
%                             Data{i,7}=logical(rls(i).groundtruth);
%                             Data{i,8}=rls(i).frameBirth;
%                             Data{i,9}=rls(i).frameEnd;
%                         end
%                         Data=Data(1:numel(rls),:);
%                         t.Data=Data;
%                         app.Data.groups(pix).Table=Data;
%                     end
%                     app.SelecttableindicesEditField.Value=['1:' num2str(numel(rls))];
%                 end
%             end

            d.Value=0.9;
            d.Message='Data loaded...';
            close(d)

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Data=[];

            gatherVarsFromWorkspace(app)

            param = newGroup(app);
            app.Data.groups=struct('Name','group01','Data',[],'Param',param,'Table',[]);
            app.Data.groups.Data.nodeid=[];
            app.Data.groups.Data.roiobj=[];

            display2(app,'datapicker')

%             app.Data.divparam=   newDivPlot(app);
%             param=app.Data.divparam;
%             tip=param.tip;
%             param=rmfield(param,'tip');
%             struct2GUI(param,[10 210],'Handle',app.divParametersPanel,'Tip',tip);
% 
%             app.Data.rlsparam=   newRLSPlot(app);
%             param=app.Data.rlsparam;
%             tip=param.tip;
%             param=rmfield(param,'tip');
%             struct2GUI(param,[10 210],'Handle',app.RLSParametersPanel,'Tip',tip);
% 
%             app.Data.signalparam=   newSignalPlot(app);
%             param=app.Data.signalparam;
%             tip=param.tip;
%             param=rmfield(param,'tip');
%             struct2GUI(param,[10 210],'Handle',app.SignalParametersPanel,'Tip',tip);

        end

        % Value changed function: DatagroupsListBox
        function DatagroupsListBoxValueChanged(app, event)
            tmp=app.DatagroupsListBox.Value;
            app.Data.selectedgroup = tmp;
            display2(app,'refresh')
        end

        % Value changed function: RenamegroupEditField
        function RenamegroupEditFieldValueChanged(app, event)
            value = app.RenamegroupEditField.Value;

            if numel(app.DatagroupsListBox.Value)
                pix=find(matches(app.DatagroupsListBox.Items,app.DatagroupsListBox.Value));
                pix=pix(1);
                app.Data.groups(pix).Name=value;
                app.DatagroupsListBox.Items{pix}=value;
                app.DatagroupsListBox.Value=value;
            end
        end

        % Button pushed function: NewgroupButton
        function NewgroupButtonPushed(app, event)
            pix=numel(app.DatagroupsListBox.Items);

            sel=find(matches(app.DatagroupsListBox.Items,app.DatagroupsListBox.Value));
            % take param value from selected position
            % here
            if numel(sel)
                param= app.Data.groups(sel).Param;
            else
                param = newGroup(app);

            end

            app.DatagroupsListBox.Items=[app.DatagroupsListBox.Items {['group' num2str(pix+1)]}];
            app.DatagroupsListBox.Value=app.DatagroupsListBox.Items{end};

            %  aa= size(app.Data.groups)

            app.Data.groups(pix+1)=app.Data.groups(pix);

            app.Data.groups(pix+1).Name=app.DatagroupsListBox.Value;
            app.Data.groups(pix+1).Param=param;
            display2(app)


        end

        % Button pushed function: DeleteselectedgroupButton
        function DeleteselectedgroupButtonPushed(app, event)
            sel=find(matches(app.DatagroupsListBox.Items,app.DatagroupsListBox.Value));
            sel=sel(1);

            if numel(sel)
                li=setxor(1:numel(app.DatagroupsListBox.Items),sel);
                app.DatagroupsListBox.Items=app.DatagroupsListBox.Items(li);
                app.Data.groups= app.Data.groups(li);
                app.DatagroupsListBox.Value=app.DatagroupsListBox.Items{1};
            end
            display2(app)
        end

        % Button pushed function: SaveselectionButton
        function SaveselectionButtonPushed(app, event)
            sel=find(matches(app.DatagroupsListBox.Items,app.DatagroupsListBox.Value));
            sel=sel(1);

            if numel(sel)
                app.Data.groups(sel).Param=app.DivisionextractionparametersPanel.UserData;

                if numel(app.Data.tree.UserData)

                    app.Data.groups(sel).Data.nodeid= app.Data.tree.UserData.nodeid;
                    app.Data.groups(sel).Data.roiobj= app.Data.tree.UserData.roiobj;
                    app.Data.groups(sel).Data.filepath= app.Data.tree.UserData.filepath;
                    app.Data.groups(sel).Data.type= app.Data.tree.UserData.type;
                end
                app.Lamp.Color=[0 1 0];
            end
        end

        % Button pushed function: AssignselectedgroupparamtoallButton
        function AssignselectedgroupparamtoallButtonPushed(app, event)
            sel=find(matches(app.DatagroupsListBox.Items,app.DatagroupsListBox.Value));
            sel=sel(1);

            if numel(sel)
                li=setxor(1:numel(app.DatagroupsListBox.Items),sel);
                if numel(li)
                    for i=li
                        app.Data.groups(i).Param=app.Data.groups(sel).Param;
                        app.Data.groups(i).Data=app.Data.groups(sel).Data;
                    end
                end
            else
                uialert(app.DivisioncountingandRLSanalysisUIFigure,'First select a data group !','Warning');
            end

        end

        % Menu selected function: LoaddatagroupsMenu
        function LoaddatagroupsMenuSelected(app, event)

            [file,path] = uigetfile('*.mat','Select a shallow project',pwd);
            if isequal(file,0)
                disp('User selected Cancel')
                return;
            else
                disp(['User selected ', fullfile(path, file)]);
                filename=fullfile(path, file);

                load(filename);
                app.Data.groups=groups;

                % check if projects are loaded ; if not , load them ...
                for i=1:numel(groups)
                    %  tmp=groups(i).Data
                    for j=1:numel(groups(i).Data.filepath)
                        if numel(groups(i).Data.filepath{j})
                            if strcmp(groups(i).Data.type{j},'shallow')
                                sha=shallowLoad(groups(i).Data.filepath{j});
                                if numel(sha)
                                    name=sha.io.file;
                                    assignin('base',name,sha);
                                end

                            end
                            if strcmp(groups(i).Data.type{j},'classi')

                                sha=classiLoad(groups(i).Data.filepath{j});
                                if numel(sha)
                                    name=[sha.strid '_indep'];
                                    assignin('base',name,sha);
                                end
                            end


                        end
                    end
                end

                display2(app,'refresh');
            end

        end

        % Menu selected function: SavedatagroupsasMenu
        function SavedatagroupsasMenuSelected(app, event)
            [file,path] = uiputfile('*.mat','Choose a data group filename',pwd);
            if isequal(file,0)
                disp('User selected Cancel')
                return;
            else
                disp(['User selected ', fullfile(path, file)]);
                filename=fullfile(path, file);

                groups=app.Data.groups;
                save(filename, 'groups');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DivisioncountingandRLSanalysisUIFigure = uifigure('Visible', 'off');
            app.DivisioncountingandRLSanalysisUIFigure.Name = 'Division counting and & RLS analysis';
            app.FileMenu = uimenu(app.DivisioncountingandRLSanalysisUIFigure);
            app.FileMenu.Text = 'File...';
            app.LoaddatagroupsMenu = uimenu(app.FileMenu);
            app.LoaddatagroupsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoaddatagroupsMenuSelected, true);
            app.LoaddatagroupsMenu.Text = 'Load data groups...';
            app.SavedatagroupsasMenu = uimenu(app.FileMenu);
            app.SavedatagroupsasMenu.MenuSelectedFcn = createCallbackFcn(app, @SavedatagroupsasMenuSelected, true);
            app.SavedatagroupsasMenu.Text = 'Save data groups as...';
            app.DatagroupsPanel = uipanel(app.DivisioncountingandRLSanalysisUIFigure);
            app.DatagroupsPanel.Title = 'Data groups';
            app.DatagroupsListBox = uilistbox(app.DatagroupsPanel);
            app.DatagroupsListBox.Items = {'group01', 'group02'};
            app.DatagroupsListBox.ValueChangedFcn = createCallbackFcn(app, @DatagroupsListBoxValueChanged, true);
            app.DatagroupsListBox.Value = 'group01';
            app.NewgroupButton = uibutton(app.DatagroupsPanel, 'push');
            app.NewgroupButton.ButtonPushedFcn = createCallbackFcn(app, @NewgroupButtonPushed, true);
            app.NewgroupButton.Text = 'New group';
            app.DeleteselectedgroupButton = uibutton(app.DatagroupsPanel, 'push');
            app.DeleteselectedgroupButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteselectedgroupButtonPushed, true);
            app.DeleteselectedgroupButton.Text = 'Delete selected group';
            app.RenamegroupEditFieldLabel = uilabel(app.DatagroupsPanel);
            app.RenamegroupEditFieldLabel.Text = 'Rename group:';
            app.RenamegroupEditField = uieditfield(app.DatagroupsPanel, 'text');
            app.RenamegroupEditField.ValueChangedFcn = createCallbackFcn(app, @RenamegroupEditFieldValueChanged, true);
            app.AssignselectedgroupparamtoallButton = uibutton(app.DatagroupsPanel, 'push');
            app.AssignselectedgroupparamtoallButton.ButtonPushedFcn = createCallbackFcn(app, @AssignselectedgroupparamtoallButtonPushed, true);
            app.AssignselectedgroupparamtoallButton.Text = 'Assign selected group param to all';
            app.ROIselectionPanel = uipanel(app.DivisioncountingandRLSanalysisUIFigure);
            app.ROIselectionPanel.Title = 'ROI selection';
            app.SaveselectionButton = uibutton(app.DivisioncountingandRLSanalysisUIFigure, 'push');
            app.SaveselectionButton.ButtonPushedFcn = createCallbackFcn(app, @SaveselectionButtonPushed, true);
            app.SaveselectionButton.Text = 'Save selection';
            app.Lamp = uilamp(app.DivisioncountingandRLSanalysisUIFigure);
            app.DatasourcePanel = uipanel(app.DivisioncountingandRLSanalysisUIFigure);
            app.DatasourcePanel.Title = 'Data source';
            app.OptionsPanel = uipanel(app.DivisioncountingandRLSanalysisUIFigure);
            app.OptionsPanel.Title = 'Options';
            app.PlotButton = uibutton(app.DivisioncountingandRLSanalysisUIFigure, 'push');
            app.PlotButton.Text = 'Plot';
            app.ExportplotsButton = uibutton(app.DivisioncountingandRLSanalysisUIFigure, 'push');
            app.ExportplotsButton.Text = 'Export plots';
            app.ExportaveragedataButton = uibutton(app.DivisioncountingandRLSanalysisUIFigure, 'push');
            app.ExportaveragedataButton.Text = 'Export average data';
            % Show the figure after all components are created
            app.DivisioncountingandRLSanalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = detector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DivisioncountingandRLSanalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DivisioncountingandRLSanalysisUIFigure)
        end
    end
end