classdef score < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScoreAppUIFigure            matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        DisplayMenu                 matlab.ui.container.Menu
        DisplaysettingsMenu         matlab.ui.container.Menu
        ROIsMenu                    matlab.ui.container.Menu
        DatasettingsMenu            matlab.ui.container.Menu
        DataMenu                    matlab.ui.container.Menu
        DataPanel                   matlab.ui.container.Panel
        UIDataAxes                  matlab.ui.control.UIAxes
        DataSettingsPanel           matlab.ui.container.Panel
        UIDataTable                 matlab.ui.control.Table
        ROisPanel                   matlab.ui.container.Panel
        UIROITable                  matlab.ui.control.Table
        DisplaysettingsPanel        matlab.ui.container.Panel
        ShapeButton                 matlab.ui.control.StateButton
        ChannelPanel                matlab.ui.container.Panel
        OverlayCheckBox             matlab.ui.control.CheckBox
        SelectedChannelLabel        matlab.ui.control.Label
        ChannelColorPicker          matlab.ui.control.ColorPicker
        ColorPickerLabel            matlab.ui.control.Label
        WeightSlider                matlab.ui.control.Slider
        WeightSliderLabel           matlab.ui.control.Label
        HighDisplaySlider           matlab.ui.control.Slider
        HighLabel                   matlab.ui.control.Label
        LowDisplaySlider            matlab.ui.control.Slider
        LowLabel                    matlab.ui.control.Label
        FrameEditField              matlab.ui.control.NumericEditField
        LineIntensityprofileButton  matlab.ui.control.StateButton
        FrameSlider                 matlab.ui.control.Slider
        FrameSliderLabel            matlab.ui.control.Label
        ZoomSlider                  matlab.ui.control.Slider
        ZoomLabel                   matlab.ui.control.Label
        UIChannelTable              matlab.ui.control.Table
        UIProfileAxes               matlab.ui.control.UIAxes
        UIDisplayAxes               matlab.ui.control.UIAxes
        UIImageAxes                 matlab.ui.control.UIAxes
    end


    properties (Access = public)
        content = struct('ROIList',[]); % Description
        SliderTickLabels % Description
        HistogramEdges % Stocke les bords des bins de l'histogramme
        HistogramData  % Stocke les valeurs de l'histogramme pour chaque canal affiché
        HistogramChannels % Stocke les indices des channels affichés dans l'histogramme
        HistogramLines  % Stocke les handles des courbes pour éviter de les redessiner
        HistogramLimits % Stocke les handles des lignes de niveaux (low/high)
        LineIntensityProfileLine   % handle de l'objet imline (initialement [])
        LineProfilePosition        % position mémorisée de la ligne (par exemple, [x1 y1; x2 y2])
        EllipseIntensityProfileObj   % Handle de l'ellipse (initialement [])
        EllipseProfilePosition       % Position mémorisée (rectangle [x y width height])
    end
    
    properties (Access = private)
        
    end

    methods (Access = private)

        % Toggle the visibility of a panel and update menu status
        function newVisibility = toggleVisibility(~, menuItem)
            if strcmp(menuItem.Checked, 'on')
                menuItem.Checked = 'off';
                newVisibility = 'off';
            else
                menuItem.Checked = 'on';
                newVisibility = 'on';
            end
        end

        function resizeUIImageAxes(app)
            % Position initiale et dimensions maximales
            baseX = 10;  % Coordonnée X de départ
            baseY = 10;  % Coordonnée Y de départ
            maxWidth = app.ScoreAppUIFigure.Position(3) - baseX;  % Largeur maximale
            maxHeight = app.ScoreAppUIFigure.Position(4) - baseY; % Hauteur maximale

            % Ajuster la taille en fonction de la visibilité des panneaux
            if strcmp(app.DisplaysettingsPanel.Visible, 'on') || strcmp(app.ROisPanel.Visible, 'on') || strcmp(app.DataSettingsPanel.Visible, 'on')
                maxWidth = maxWidth - app.DisplaysettingsPanel.Position(3);
                baseX=baseX+app.DisplaysettingsPanel.Position(3);
            end

            if strcmp(app.DataPanel.Visible, 'on')
                maxWidth = maxWidth - app.DataPanel.Position(3)-10;
            end

            % Mettre à jour la position et la taille de UIImageAxes
            app.UIImageAxes.Position = [baseX, baseY, maxWidth, maxHeight];
        end

       function displayROIs(app)
    % Vérifie si des ROIs sont présentes
    if isempty(app.content.ROIList)
        app.UIROITable.Data = {}; % Vide la table si aucune ROI
        return;
    end

    % Préparer les colonnes : Display, Name, Size
    numROIs = numel(app.content.ROIList);
    tableData = cell(numROIs, 3);

    for i = 1:numROIs
        roi = app.content.ROIList{i}; % Récupérer l'instance de la ROI

        if i==numROIs
        tableData{i, 1} = true; % Désactiver l'affichage par défaut
        else
        tableData{i, 1} = false; % Désactiver l'affichage par défaut
        end

        tableData{i, 2} = roi.id; % Nom de la ROI
        tableData{i, 3} = sprintf('%dx%d', size(roi.image, 1), size(roi.image, 2)); % Taille de l'image
    end

    % Mettre à jour la table
    app.UIROITable.Data = tableData;
    app.UIROITable.ColumnName = {'Display', 'Name', 'Size'};
    
    % Ajuster les largeurs des colonnes
    app.UIROITable.ColumnWidth = {50, 'auto', 60};

    % Définir un callback pour gérer les sélections
    app.UIROITable.CellEditCallback = @(src, event) app.handleROITableEdit(event);

    displayROIChannels(app);
    score_display(app, 'slow');

end

function handleROITableEdit(app, event)
    % Callback pour s'assurer qu'une seule ROI est sélectionnée
    if event.Indices(2) == 1 % Si la colonne "Display" est modifiée
        % Désactiver toutes les cases sauf celle cliquée
        numROIs = size(app.UIROITable.Data, 1);
        for i = 1:numROIs
            app.UIROITable.Data{i, 1} = false;
        end

        % Activer uniquement la ligne sélectionnée
        app.UIROITable.Data{event.Indices(1), 1} = true;

        % Mettre à jour la table
        app.UIROITable.Data = app.UIROITable.Data;

        % Optionnel : effectuer des actions basées sur la sélection
        selectedROI = app.content.ROIList{event.Indices(1)};
        disp(['Selected ROI: ', selectedROI.id]);

         displayROIChannels(app);

         numFrames = size(selectedROI.image, 4);

    % Mettre à jour les limites du slider
    app.FrameSlider.Limits = [1 numFrames];
    
    % Réinitialiser la valeur du slider (optionnel)
    app.FrameSlider.Value = selectedROI.display.frame;

         score_display(app, 'slow');
    end
end
        
function displayROIChannels(app)
    % Vérifier si des ROIs sont présentes dans l'application
    if isempty(app.content.ROIList)
        app.UIChannelTable.Data = {}; % Vide la table si aucune ROI
        return;
    end

    % Trouver l'index de la ROI actuellement sélectionnée
    selectedIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1); % Cherche la première ROI cochée
    if isempty(selectedIndex)
        app.UIChannelTable.Data = {}; % Vide la table si aucune ROI n'est sélectionnée
        return;
    end

    % Récupérer la ROI sélectionnée
    selectedROI = app.content.ROIList{selectedIndex};

    % Vérifier si la ROI a des canaux disponibles
    if isempty(selectedROI.display.channel)
        app.UIChannelTable.Data = {}; % Vide la table si aucun canal
        return;
    end

    % Préparer la liste des canaux affichables : 
    % On inclut les canaux dont l'intensité n'est pas nulle et aussi ceux qui correspondent à des images RGB (3 canaux)
    numChannels = numel(selectedROI.display.channel);
    colorChannels = [];
    for i = 1:numChannels
        % Vérifier si le canal correspond à une image RGB
        pix = selectedROI.findChannelID(selectedROI.display.channel{i});
        if numel(pix)==3
            % Canal RGB détecté, on l'inclut
            colorChannels = [colorChannels i];
        else
            % Pour les autres canaux, on ne les inclut que si l'intensité n'est pas nulle
            if sum(selectedROI.display.intensity(i, :)) == 0
                continue;
            end
            colorChannels = [colorChannels i];
        end
    end

    % Préparer les colonnes pour la table : Display, Name, Levels, RGB, Weight, Auto
    tableData = cell(numel(colorChannels), 6);
    for i = 1:numel(colorChannels)
        chIndex = colorChannels(i);
        % Colonne "Display" : checkbox indiquant si le canal doit être affiché
        tableData{i, 1} = logical(selectedROI.display.selectedchannel(chIndex));
        % Colonne "Name" : nom du canal
        tableData{i, 2} = selectedROI.display.channel{chIndex};
        % Colonne "Levels" : niveaux d'affichage (convertis en échelle 0-65535)
        tableData{i, 3} = sprintf('%.0f %.0f', round(65535 * selectedROI.display.displaylim(1, chIndex)), round(65535 * selectedROI.display.displaylim(2, chIndex)));
        % Colonne "RGB" : affichage de la couleur (pour les images RGB, vous pouvez afficher par exemple 'RGB' ou la valeur si définie)
        if numel(selectedROI.findChannelID(selectedROI.display.channel{chIndex}))==3
            % Optionnel : vous pouvez afficher "RGB" ou une valeur par défaut
            tableData{i, 4} = 'RGB';
        else
            tableData{i, 4} = sprintf('%.1f %.1f %.1f', selectedROI.display.rgb(chIndex, :));
        end
        % Colonne "Weight" : poids du canal
        tableData{i, 5} = sprintf('%.1f', selectedROI.display.intensity(chIndex, 1));
        % Colonne "Auto" : par défaut, "Auto" est désactivé
        tableData{i, 6} = false;
    end

    % Mettre à jour la table et ses propriétés
    app.UIChannelTable.Data = tableData;
    app.UIChannelTable.ColumnName = {'Display', 'Name', 'Levels', 'RGB', 'Weight', 'Auto'};
    app.UIChannelTable.ColumnWidth = {40, 100, 80, 80, 60, 45};
    % Rendre certaines colonnes éditables (ici Display, Levels, RGB, Weight, Auto)
    app.UIChannelTable.ColumnEditable = [true, false, true, true, true, true];

    % Définir le callback pour gérer les modifications
    app.UIChannelTable.CellEditCallback = @(src, event) app.handleChannelTableEdit(event, selectedROI, colorChannels);

    % Mettre à jour l'affichage des features du channel et redessiner l'image si besoin
    displayChannelFeatures(app);
    score_display(app, 'slow');
end


function handleChannelTableEdit(app, event, selectedROI, colorChannels)
    % Callback pour gérer l'édition de la table des canaux
    rowIndex = event.Indices(1); % Index de la ligne modifiée
    colIndex = event.Indices(2); % Index de la colonne modifiée
    chIndex = colorChannels(rowIndex); % Trouver le bon index du channel

    switch colIndex
        case 1 % Modification dans la colonne "Display" (checkbox)
            selectedROI.display.selectedchannel(chIndex) = event.NewData;

        case 3 
            tmp=str2num(event.NewData);
            selectedROI.display.displaylim(:, chIndex) = double(tmp/65535);
            

        case 4 % Modification dans la colonne "RGB"
            % Valider l'entrée comme un triplet RGB
         rgbValues = str2double(strsplit(event.NewData, {' ', ','}));

        if numel(rgbValues) == 3 && all(~isnan(rgbValues))
               disp('RGB values parsed successfully');
        else
                disp('Invalid RGB input format');
        end

            if numel(rgbValues) == 3 && all(rgbValues >= 0 & rgbValues <= 1)
                selectedROI.display.rgb(chIndex, :) = rgbValues';
            else
                uialert(app.ScoreAppUIFigure, 'RGB values must be three numbers between 0 and 1.', 'Invalid Input');
                app.displayROIChannels(); % Réinitialiser la table
            end

        case 5 % Modification dans la colonne "Weight"
            % Valider l'entrée comme un nombre positif
            weightValue = str2double(event.NewData);
            if ~isnan(weightValue) && weightValue >= 0
                selectedROI.display.intensity(chIndex) = min(1,max(0.01,weightValue));

            else
                uialert(app.ScoreAppUIFigure, 'Weight must be a positive number.', 'Invalid Input');
                app.displayROIChannels(); % Réinitialiser la table
            end

        case 6 % Modification dans la colonne "Auto" (checkbox pour auto-adjust)
            if event.NewData
                % Appliquer un auto-ajustement des niveaux
                selectedROI.computeDisplaylim;
                app.displayROIChannels(); % Mettre à jour la table
            end
    end

    displayChannelFeatures(app);
     score_display(app, 'refresh');
    % event=[];
    % event.Value= log10(selectedROI.display.displaylim(1, chIndex));
    % LowDisplaySliderValueChanging(app, event);
    % event.Value= log10(selectedROI.display.displaylim(2, chIndex));
    % HighDisplaySliderValueChanging(app, event);
end


 function displayChannelFeatures(app)
    % Vérifier si une ROI est sélectionnée
    if isempty(app.content.ROIList)
        return; % Pas de ROI, rien à afficher
    end

    % Trouver la ROI actuellement sélectionnée
    selectedROIIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1);
    if isempty(selectedROIIndex)
        return; % Pas de ROI sélectionnée, quitter
    end

    % Récupérer la ROI sélectionnée
    selectedROI = app.content.ROIList{selectedROIIndex};

    % Vérifier si un canal est sélectionné dans la table des canaux
    selectedChannelIndex = app.UIChannelTable.Selection;
    if isempty(selectedChannelIndex) || isempty(selectedChannelIndex(1))
        return; % Pas de canal sélectionné, quitter
    end

    %channelIndex = selectedChannelIndex(1); % Index du canal sélectionné
        % Trouver le bon index du canal basé sur son nom dans `selectedROI.display.channel`
    selectedChannelName = app.UIChannelTable.Data{selectedChannelIndex, 2}; % Nom du canal sélectionné
    channelIndex = find(strcmp(selectedROI.display.channel, selectedChannelName), 1);


    % Définir les bornes des sliders en échelle logarithmique
    minVal = 1; % On évite 0 car log(0) est indéfini
    maxVal = 65535;
    logMin = log10(minVal);
    logMax = log10(maxVal);

    % Mettre à jour les limites des sliders
    app.LowDisplaySlider.Limits = [logMin, logMax];
    app.HighDisplaySlider.Limits = [logMin, logMax];

    % Appliquer la transformation logarithmique pour récupérer la position correcte
    lowLevel = max(65535*selectedROI.display.displaylim(1, channelIndex), 1);
    highLevel = min(65535*selectedROI.display.displaylim(2, channelIndex), 65535);
    
    app.LowDisplaySlider.Value = log10(lowLevel);
   
    app.HighDisplaySlider.Value = log10(highLevel);

    % Mettre à jour le color picker RGB
    rgbValues = selectedROI.display.rgb(channelIndex, :);
    app.ChannelColorPicker.Value = rgbValues;

    weightValue = min(1,max(0,selectedROI.display.intensity(channelIndex, 1))); % Normalisé entre 0 et 1
    app.WeightSlider.Value = weightValue;

    % Configurer les callbacks MATLAB natifs
    %app.LowDisplaySlider.ValueChangingFcn = @(src, event) app.LowDisplaySliderValueChanging(event, channelIndex);
    %app.HighDisplaySlider.ValueChangingFcn = @(src, event) app.HighDisplaySliderValueChanging(event, channelIndex);

    % Configurer le color picker pour mettre à jour les valeurs RGB
    %app.ChannelColorPicker.ValueChangedFcn = @(src, event) app.updateRGB(channelIndex);
end

function updateChannelTable(app, selectedROI)
    % Vérifier si la table est vide ou si aucun canal n'est affiché
    if isempty(app.UIChannelTable.Data)
        return;
    end

    % Trouver les canaux à afficher (colorChannels)
    numChannels = size(selectedROI.display.rgb, 1);
    colorChannels = [];

    for i = 1:numChannels
        if sum(selectedROI.display.intensity(i, :)) == 0 
            continue; % Skip les canaux indexés (intensity == 0)
        end
        colorChannels = [colorChannels, i]; % Stocke les indices des canaux affichables
    end

    % Vérifier qu'on a bien des canaux valides
    if isempty(colorChannels)
        app.UIChannelTable.Data = {}; % Vide la table si aucun canal valide
        return;
    end

    % Récupérer les données actuelles de la table
    tableData = app.UIChannelTable.Data;
    
    for i = 1:numel(colorChannels)
        chanIndex = colorChannels(i); % Indice réel du canal dans `selectedROI.display`
        
        % Mettre à jour la colonne "Levels" (conversion en 65535)
        tableData{i, 3} = sprintf('%.2f %.2f', ...
            round(65535 * selectedROI.display.displaylim(1, chanIndex)), ...
            round(65535 * selectedROI.display.displaylim(2, chanIndex)));
        
        % Mettre à jour la colonne "RGB" (sans crochets)
        tableData{i, 4} = sprintf('%.2f %.2f %.2f', selectedROI.display.rgb(chanIndex, :));
        
        % Mettre à jour la colonne "Weight"
        tableData{i, 5} = sprintf('%.2f', selectedROI.display.intensity(chanIndex, 1));
    end

    % Mettre à jour les données de la table
    app.UIChannelTable.Data = tableData;
    score_display(app, 'refresh');
end

    end

    methods (Access = public)
        function addROI(app,roiobj)
            % Vérifier si ROI existe déjà dans app.content.ROIList
            if isempty(app.content.ROIList)
                isPresent = false;
            else
                % Vérifier si une ROI avec le même ID existe
                isPresent = any(cellfun(@(roi) strcmp(roi.id, roiobj.id), app.content.ROIList));
            end

            if isPresent
                disp(['ROI with ID "', roiobj.id, '" is already present. Skipping addition.']);
            else
                % Ajouter la ROI à la liste
                if numel(roiobj.image)==0
                    roiobj.load;
                end

                app.content.ROIList{end+1} = roiobj;
                disp(['Adding ROI: ', roiobj.id]);

                % Mettre à jour l'affichage de la table
                app.displayROIs();
            end
        end

    function updateIntensityProfile(app, pos)
    % Récupérer la ROI actuellement sélectionnée
    selectedROIIndex = find(cell2mat(app.UIChannelTable.Data(:,1)), 1);
    if isempty(selectedROIIndex)
        return;
    end
    selectedROI = app.content.ROIList{selectedROIIndex};
    currentFrame = selectedROI.display.frame;
    
    % Récupérer la liste des canaux "affichables" (colorChannels) 
    % On inclut les canaux pour lesquels findChannelID renvoie 3 indices (RGB)
    % ou ceux pour lesquels l'intensité n'est pas nulle.
    numTotalChannels = numel(selectedROI.display.channel);
    colorChannels = [];
    for i = 1:numTotalChannels
        pix = selectedROI.findChannelID(selectedROI.display.channel{i});
        if numel(pix)==3
            colorChannels = [colorChannels, i];
        else
            if sum(selectedROI.display.intensity(i, :)) == 0
                continue;
            end
            colorChannels = [colorChannels, i];
        end
    end

    % Récupérer les cases cochées depuis la table (colonne 1) et déterminer les channels affichés
    if isempty(app.UIChannelTable.Data)
        cla(app.UIProfileAxes);
        return;
    end
    checkboxValues = cell2mat(app.UIChannelTable.Data(:,1));
    displayedChannels = colorChannels(checkboxValues);
    
    if isempty(displayedChannels)
        cla(app.UIProfileAxes);
        return;
    end

    % Préparer le tracé : effacer et activer hold on sur l'axe des profils
    cla(app.UIProfileAxes);
    hold(app.UIProfileAxes, 'on');
    
    % Extraire les coordonnées de la ligne à partir de pos
    % pos est une matrice 2x2 : [x1 y1; x2 y2]
    xCoords = [pos(1,1), pos(2,1)];
    yCoords = [pos(1,2), pos(2,2)];
    
    % Pour chaque canal affiché, extraire et tracer son profil d'intensité
    profiles = cell(1, length(displayedChannels));
    channelNames = cell(1, length(displayedChannels));
    for k = 1:length(displayedChannels)
        channelIdx = displayedChannels(k);
        % Récupérer le nom de ce canal
        baseName = selectedROI.display.channel{channelIdx};
        
        % Vérifier si ce canal est un canal RGB (trois indices)
        pix = selectedROI.findChannelID(selectedROI.display.channel{channelIdx});
        if numel(pix)==3
            % Pour un canal RGB, extraire les 3 composantes brutes et calculer un profil en niveaux de gris
            rawR = double(selectedROI.image(:, :, pix(1), currentFrame));
            rawG = double(selectedROI.image(:, :, pix(2), currentFrame));
            rawB = double(selectedROI.image(:, :, pix(3), currentFrame));
            % Conversion en niveaux de gris (coefficients standards)
            grayImage = 0.2989 * rawR + 0.5870 * rawG + 0.1140 * rawB;
            profile = improfile(grayImage, xCoords, yCoords);
        else
            % Pour un canal monochrome, extraire directement les intensités brutes
            rawImg = double(selectedROI.image(:, :, channelIdx, currentFrame));
            profile = improfile(rawImg, xCoords, yCoords);
        end
        profile = squeeze(profile);  % éliminer les dimensions unitaires
        profiles{k} = profile;
        
        % Calculer les valeurs min et max du profil
        minVal = min(profile(:));
        maxVal = max(profile(:));
        
        % Mettre à jour le nom du canal avec ces valeurs pour la légende
        channelNames{k} = sprintf('%s (min=%.0f, max=%.0f)', baseName, minVal, maxVal);
        
        % Déterminer la couleur d'affichage du profil à partir de selectedROI.display.rgb
        rgbColor = selectedROI.display.rgb(channelIdx, :);
        if all(rgbColor >= 0.99)
            plotColor = [0, 0, 0];
        else
            plotColor = rgbColor;
        end
        
        % Tracer le profil dans app.UIProfileAxes
        plot(app.UIProfileAxes, profile, 'Color', plotColor, 'LineWidth', 2);
    end

    hold(app.UIProfileAxes, 'off');
    xlabel(app.UIProfileAxes, 'Pixel Position along Line');
    ylabel(app.UIProfileAxes, 'Raw Intensity');
    legend(app.UIProfileAxes, channelNames, 'Interpreter', 'none');
end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, roiobj)
            % Connecter les callbacks des menus
            % app.DisplaysettingsMenu.MenuSelectedFcn = @app.DisplaysettingsMenuSelected;
            % app.ROIsMenu.MenuSelectedFcn = @app.ROIsMenuSelected;
            % app.DatasettingsMenu.MenuSelectedFcn = @app.DatasettingsMenuSelected;
            % app.DataMenu.MenuSelectedFcn = @app.DataMenuSelected;

            app.addROI(roiobj);
            % Initialiser l'état des panneaux
            app.DisplaysettingsPanel.Visible = 'on';
            app.ROisPanel.Visible = 'on';
            app.DataSettingsPanel.Visible = 'on';
            app.DataPanel.Visible = 'off';
            app.resizeUIImageAxes();

        end

        % Callback function
        function DisplaysettingsMenuSelected(app, event)

        end

        % Callback function
        function ROIsMenuSelected(app, event)

        end

        % Callback function
        function DatasettingsMenuSelected(app, event)

        end

        % Callback function
        function DataMenuSelected(app, event)

        end

        % Callback function
        function DisplaysettingsMenuSelected2(app, event)

        end

        % Menu selected function: ROIsMenu
        function ROIsMenuSelected2(app, event)
            app.ROisPanel.Visible = app.toggleVisibility(app.ROIsMenu);
            app.resizeUIImageAxes();  % Ajuster UIImageAxes
        end

        % Menu selected function: DatasettingsMenu
        function DatasettingsMenuSelected2(app, event)
            app.DataSettingsPanel.Visible = app.toggleVisibility(app.DatasettingsMenu);
            app.resizeUIImageAxes();  % Ajuster UIImageAxes
        end

        % Menu selected function: DataMenu
        function DataMenuSelected2(app, event)
            app.DataPanel.Visible = app.toggleVisibility(app.DataMenu);
            app.resizeUIImageAxes();  % Ajuster UIImageAxes
        end

        % Menu selected function: DisplaysettingsMenu
        function DisplaysettingsMenuSelected3(app, event)
            app.DisplaysettingsPanel.Visible = app.toggleVisibility(app.DisplaysettingsMenu);
            app.resizeUIImageAxes();  % Ajuster UIImageAxes
        end

        % Selection changed function: UIChannelTable
        function UIChannelTableSelectionChanged(app, event)
            selection = app.UIChannelTable.Selection;
             % Vérifier si une ROI est sélectionnée
    if isempty(app.content.ROIList)
        app.SelectedChannelLabel.Text = 'Selected Channel: None';
        return;
    end

    % Trouver la ROI actuellement sélectionnée
    selectedROIIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1);
    if isempty(selectedROIIndex)
        app.SelectedChannelLabel.Text = 'Selected Channel: None';
        return;
    end

    % Vérifier si un canal est sélectionné dans la table
    selectedChannelIndex = app.UIChannelTable.Selection;
    if isempty(selectedChannelIndex) || isempty(selectedChannelIndex(1))
        app.SelectedChannelLabel.Text = 'Selected Channel: None';
        return;
    end

    % Récupérer le nom du canal sélectionné
    selectedChannelName = app.UIChannelTable.Data{selectedChannelIndex(1), 2};

    % Mettre à jour le label avec le nom du canal sélectionné
    app.SelectedChannelLabel.Text = sprintf('Selected Channel: %s', selectedChannelName);

    % Mettre à jour les sliders et le color picker pour refléter les paramètres du canal
    displayChannelFeatures(app);
            displayChannelFeatures(app);
        end

        % Value changing function: LowDisplaySlider
        function LowDisplaySliderValueChanging(app, event)
            changingValue = event.Value;
            % Vérifie si une ROI est sélectionnée
    selectedROIIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1);
    if isempty(selectedROIIndex)
        return;
    end

    % Récupère la ROI sélectionnée
    selectedROI = app.content.ROIList{selectedROIIndex};

    % Vérifie si un canal est sélectionné
    if isempty(app.UIChannelTable.Selection)
        return;
    end

    % Récupère l'index du canal sélectionné
    channelIndex = app.UIChannelTable.Selection(1);
    if isempty(channelIndex)
        return;
    end

    selectedChannelName = app.UIChannelTable.Data{channelIndex, 2}; % Nom du canal sélectionné
    channelIndex = find(strcmp(selectedROI.display.channel, selectedChannelName), 1);

    % Met à jour la limite basse en échelle linéaire
    selectedROI.display.displaylim(1, channelIndex) = 10^(event.Value)/65535;

    % Mise à jour dynamique de la table
    updateChannelTable(app, selectedROI);

    % Met à jour l'affichage de l'image en direct
   % updateImageDisplay(app);
            
        end

        % Value changing function: HighDisplaySlider
        function HighDisplaySliderValueChanging(app, event)
            changingValue = event.Value;
             % Vérifie si une ROI est sélectionnée
    selectedROIIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1);
    if isempty(selectedROIIndex)
        return;
    end

    % Récupère la ROI sélectionnée
    selectedROI = app.content.ROIList{selectedROIIndex};

    % Vérifie si un canal est sélectionné
    if isempty(app.UIChannelTable.Selection)
        return;
    end

    % Récupère l'index du canal sélectionné
    channelIndex = app.UIChannelTable.Selection(1);
    if isempty(channelIndex)
        return;
    end

    selectedChannelName = app.UIChannelTable.Data{channelIndex, 2}; % Nom du canal sélectionné
    channelIndex = find(strcmp(selectedROI.display.channel, selectedChannelName), 1);

    % Met à jour la limite haute en échelle linéaire
    selectedROI.display.displaylim(2, channelIndex) = 10^(event.Value)/65535;
       
    updateChannelTable(app, selectedROI);

    % Met à jour l'affichage de l'image en direct
    %updateImageDisplay(app);
        end

        % Value changing function: ZoomSlider
        function ZoomSliderValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            changingValue = event.Value;
            
    % Récupérer la nouvelle valeur du slider et l'arrondir
    newFrame = round(event.Value);

    % Vérifier qu'une ROI est présente
    if isempty(app.content.ROIList)
        return;
    end

    % Trouver l'index de la ROI actuellement sélectionnée (celle cochée dans la table)
    selectedROIIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1);
    if isempty(selectedROIIndex)
        return;
    end
    selectedROI = app.content.ROIList{selectedROIIndex};

    % Obtenir le nombre total de frames pour cette ROI
    numFrames = size(selectedROI.image, 4);
    % S'assurer que newFrame est dans l'intervalle valide
    if newFrame < 1
        newFrame = 1;
    elseif newFrame > numFrames
        newFrame = numFrames;
    end

    % Mettre à jour le numéro de frame dans la ROI
    selectedROI.display.frame = newFrame;
    app.FrameEditField.Value=selectedROI.display.frame;

    % Mettre à jour l'affichage (image et histogramme) en mode "refresh"
    score_display(app, 'refresh');

        end

        % Value changing function: WeightSlider
        function WeightSliderValueChanging(app, event)
            changingValue = event.Value;
            
              % Vérifier si une ROI est sélectionnée
    if isempty(app.content.ROIList)
        return;
    end

    % Trouver l'index de la ROI actuellement sélectionnée
    selectedROIIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1);
    if isempty(selectedROIIndex)
        return;
    end

    % Récupérer la ROI sélectionnée
    selectedROI = app.content.ROIList{selectedROIIndex};

    % Vérifier si un canal est sélectionné
    selectedChannelIndex = app.UIChannelTable.Selection;
    if isempty(selectedChannelIndex) || isempty(selectedChannelIndex(1))
        return;
    end

    % Trouver l'index réel du canal dans colorChannels
    channelName = app.UIChannelTable.Data{selectedChannelIndex(1), 2}; % Nom du canal
    channelIndex = find(strcmp(selectedROI.display.channel, channelName), 1);

    if isempty(channelIndex)
        return;
    end

    % Mettre à jour la valeur du poids dans selectedROI
    newWeight = max(0.01,event.Value); % Récupérer la nouvelle valeur du slider
    selectedROI.display.intensity(channelIndex, 1) = newWeight;

    % Mettre à jour la colonne "Weight" dans la table des channels
    updateChannelTable(app, selectedROI);
   % app.UIChannelTable.Data{selectedChannelIndex(1), 5} = sprintf('%.2f', newWeight);

        end

        % Callback function: ChannelColorPicker
        function ChannelColorPickerValueChanged(app, event)
            value = app.ChannelColorPicker.Value;
               % Vérifier si une ROI est sélectionnée
    if isempty(app.content.ROIList)
        return;
    end

    % Trouver l'index de la ROI actuellement sélectionnée
    selectedROIIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1);
    if isempty(selectedROIIndex)
        return;
    end

    % Récupérer la ROI sélectionnée
    selectedROI = app.content.ROIList{selectedROIIndex};

    % Vérifier si un canal est sélectionné dans la table des canaux
    selectedChannelIndex = app.UIChannelTable.Selection;
    if isempty(selectedChannelIndex) || isempty(selectedChannelIndex(1))
        return;
    end

    % Trouver l'index réel du canal dans colorChannels
    channelName = app.UIChannelTable.Data{selectedChannelIndex(1), 2}; % Nom du canal
    channelIndex = find(strcmp(selectedROI.display.channel, channelName), 1);

    if isempty(channelIndex)
        return;
    end

    % Récupérer la nouvelle couleur du color picker
    newRGB = event.Value;

    % Mettre à jour la couleur RGB dans selectedROI
    selectedROI.display.rgb(channelIndex, :) = newRGB;

    % Mettre à jour la colonne "RGB" dans la table des canaux
    updateChannelTable(app, selectedROI);
    %app.UIChannelTable.Data{selectedChannelIndex(1), 4} = sprintf('%.2f %.2f %.2f', newRGB);
        end

        % Value changed function: FrameEditField
        function FrameEditFieldValueChanged(app, event)
            
             % Récupérer la nouvelle valeur entrée par l'utilisateur et l'arrondir
    newFrame = round(app.FrameEditField.Value);
    
    % Vérifier qu'une ROI est présente
    if isempty(app.content.ROIList)
        return;
    end

    % Récupérer la ROI sélectionnée
    selectedROIIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1);
    if isempty(selectedROIIndex)
        return;
    end
    selectedROI = app.content.ROIList{selectedROIIndex};
    
    % Déterminer le nombre de frames disponibles
    numFrames = size(selectedROI.image, 4);
    
    % Contraindre newFrame à l'intervalle [1, numFrames]
    if newFrame < 1
        newFrame = 1;
    elseif newFrame > numFrames
        newFrame = numFrames;
    end
    
    % Mettre à jour le slider et la propriété de la ROI
    app.FrameSlider.Value = newFrame;
    selectedROI.display.frame = newFrame;
    
    % Mettre à jour le champ d'édition (au cas où il ait été ajusté)
    app.FrameEditField.Value = newFrame;
    
    % Rafraîchir l'affichage (image et histogramme)
    score_display(app, 'refresh');
        end

        % Key press function: ScoreAppUIFigure
        function ScoreAppUIFigureKeyPress(app, event)
            
            % Si aucune ROI n'est présente, ne rien faire
    if isempty(app.content.ROIList)
        return;
    end
    
    % Récupérer l'index de la ROI sélectionnée (celle cochée dans la table)
    selectedROIIndex = find(cell2mat(app.UIROITable.Data(:, 1)), 1);
    if isempty(selectedROIIndex)
        return;
    end
    selectedROI = app.content.ROIList{selectedROIIndex};
    
    % Déterminer l'incrément selon la touche fléchée pressée
    switch event.Key
        case 'rightarrow'
            increment = 1;
        case 'leftarrow'
            increment = -1;
        otherwise
            return; % Ignorer d'autres touches
    end
    
    % Récupérer la frame courante et le nombre total de frames
    currentFrame = selectedROI.display.frame;
    numFrames = size(selectedROI.image, 4);
    
    % Calculer la nouvelle frame en s'assurant qu'elle reste dans l'intervalle [1, numFrames]
    newFrame = currentFrame + increment;
    newFrame = max(1, min(newFrame, numFrames));
    
    % Mettre à jour la propriété de la ROI, le slider et le champ d'édition
    selectedROI.display.frame = newFrame;
    app.FrameSlider.Value = newFrame;
    app.FrameEditField.Value = newFrame;
    
    % Rafraîchir l'affichage (image et histogramme)
    score_display(app, 'refresh');
        end

        % Value changed function: OverlayCheckBox
        function OverlayCheckBoxValueChanged(app, event)
            value = app.OverlayCheckBox.Value;
            app.LineIntensityprofileButton.Value=false;
            LineIntensityprofileButtonValueChanged2(app, event);
            score_display(app, 'slow');
        end

        % Callback function: not associated with a component
        function LineIntensityprofileButtonValueChanged(app, event)


        end

        % Value changed function: LineIntensityprofileButton
        function LineIntensityprofileButtonValueChanged2(app, event)
            value = app.LineIntensityprofileButton.Value;

         if app.LineIntensityprofileButton.Value
        % Bouton activé : afficher la ligne de profil
        if ~isempty(app.LineIntensityProfileLine) && isvalid(app.LineIntensityProfileLine)
            % La ligne existe déjà ; vous pouvez éventuellement la laisser telle quelle
            % ou la mettre au premier plan (par exemple, en la recréant si nécessaire)
        else
            % Déterminer la position initiale de la ligne
            if ~isempty(app.LineProfilePosition)
                pos = app.LineProfilePosition;  % Utiliser la position mémorisée
            else
                % Position par défaut : ligne horizontale au milieu de l'axe de l'image
                xLimits = app.UIImageAxes.XLim;
                yLimits = app.UIImageAxes.YLim;
                pos = [xLimits(1)+10, mean(yLimits); xLimits(2)-10, mean(yLimits)];
            end
            % Créer la ligne sur l'axe de l'image
            app.LineIntensityProfileLine = imline(app.UIImageAxes, pos);
            % Ajouter un callback pour mettre à jour le profil quand la ligne est déplacée
            addNewPositionCallback(app.LineIntensityProfileLine, @(newPos) updateIntensityProfile(app, newPos));
        end
        % Mise à jour immédiate du profil d'intensité avec la position actuelle de la ligne
        updateIntensityProfile(app, getPosition(app.LineIntensityProfileLine));
    else
        % Bouton désactivé : mémoriser la position et supprimer la ligne
        if ~isempty(app.LineIntensityProfileLine) && isvalid(app.LineIntensityProfileLine)
            app.LineProfilePosition = getPosition(app.LineIntensityProfileLine);
            delete(app.LineIntensityProfileLine);  % Supprime la ligne
            app.LineIntensityProfileLine = [];
        end
        % Effacer le plot du profil d'intensité
        cla(app.UIProfileAxes);
    end


        end

        % Value changed function: ShapeButton
        function ShapeButtonValueChanged(app, event)
            value = app.ShapeButton.Value;
        
            if value
        % Bouton activé : afficher ou créer l'ellipse de profil
        if isempty(app.EllipseIntensityProfileObj) || ~isvalid(app.EllipseIntensityProfileObj)
            % Déterminer la position initiale de l'ellipse
            if ~isempty(app.EllipseProfilePosition)
                % Utiliser la position mémorisée (format [x y width height])
                pos = app.EllipseProfilePosition;
                center = [pos(1) + pos(3)/2, pos(2) + pos(4)/2];
                semiAxes = [pos(3)/2, pos(4)/2];
            else
                % Position par défaut : ellipse centrée dans l'axe de l'image
                xLimits = app.UIImageAxes.XLim;
                yLimits = app.UIImageAxes.YLim;
                width  = (xLimits(2)-xLimits(1)) * 0.5;
                height = (yLimits(2)-yLimits(1)) * 0.5;
                center = [mean(xLimits), mean(yLimits)];
                semiAxes = [width/2, height/2];
            end
            % Créer l'ellipse en spécifiant 'Center' et 'SemiAxes'
            app.EllipseIntensityProfileObj = drawellipse(app.UIImageAxes, ...
                'Center', center, 'SemiAxes', semiAxes);
            % Définir le callback ROIMovedFcn pour mettre à jour le profil lorsque l'ellipse est déplacée
            app.EllipseIntensityProfileObj.ROIMovedFcn = @(roi, eventData) score_updateEllipticalProfile(app, roi);
        end
        % Mise à jour immédiate du profil avec l'ellipse actuelle
        score_updateEllipticalProfile(app, app.EllipseIntensityProfileObj);
        % Pour obtenir une mise à jour en temps réel pendant le déplacement, 
        % on définit un WindowButtonMotionFcn sur la figure principale
        app.ScoreAppUIFigure.WindowButtonMotionFcn = @(src, evt) ...
            (isempty(app.EllipseIntensityProfileObj) || ~isvalid(app.EllipseIntensityProfileObj)) ...
            ? [] : score_updateEllipticalProfile(app, app.EllipseIntensityProfileObj);
    else
        % Bouton désactivé : désactiver le WindowButtonMotionFcn
        app.ScoreAppUIFigure.WindowButtonMotionFcn = '';
        % Sauvegarder la position actuelle de l'ellipse au format [x y width height]
        if ~isempty(app.EllipseIntensityProfileObj) && isvalid(app.EllipseIntensityProfileObj)
            center = app.EllipseIntensityProfileObj.Center;
            semiAxes = app.EllipseIntensityProfileObj.SemiAxes;
            app.EllipseProfilePosition = [center(1)-semiAxes(1), center(2)-semiAxes(2), 2*semiAxes(1), 2*semiAxes(2)];
            delete(app.EllipseIntensityProfileObj);
            app.EllipseIntensityProfileObj = [];
        end
        % Effacer le profil affiché
        cla(app.UIProfileAxes);
    end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScoreAppUIFigure = uifigure('Visible', 'off');
            app.ScoreAppUIFigure.Name = 'ScoreApp';
            app.ScoreAppUIFigure.KeyPressFcn = createCallbackFcn(app, @ScoreAppUIFigureKeyPress, true);
            app.FileMenu = uimenu(app.ScoreAppUIFigure);
            app.FileMenu.Text = 'File';
            app.DisplayMenu = uimenu(app.ScoreAppUIFigure);
            app.DisplayMenu.Text = 'Display';
            app.DisplaysettingsMenu = uimenu(app.DisplayMenu);
            app.DisplaysettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @DisplaysettingsMenuSelected3, true);
            app.DisplaysettingsMenu.Text = 'Display settings';
            app.ROIsMenu = uimenu(app.DisplayMenu);
            app.ROIsMenu.MenuSelectedFcn = createCallbackFcn(app, @ROIsMenuSelected2, true);
            app.ROIsMenu.Text = 'ROIs';
            app.DatasettingsMenu = uimenu(app.DisplayMenu);
            app.DatasettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @DatasettingsMenuSelected2, true);
            app.DatasettingsMenu.Text = 'Data settings';
            app.DataMenu = uimenu(app.DisplayMenu);
            app.DataMenu.MenuSelectedFcn = createCallbackFcn(app, @DataMenuSelected2, true);
            app.DataMenu.Text = 'Data';
            app.UIImageAxes = uiaxes(app.ScoreAppUIFigure);
            title(app.UIImageAxes, 'ROI #')
            xlabel(app.UIImageAxes, 'X')
            ylabel(app.UIImageAxes, 'Y')
            zlabel(app.UIImageAxes, 'Z')
            app.DisplaysettingsPanel = uipanel(app.ScoreAppUIFigure);
            app.DisplaysettingsPanel.Title = 'Display settings';
            app.UIDisplayAxes = uiaxes(app.DisplaysettingsPanel);
            xlabel(app.UIDisplayAxes, 'X')
            ylabel(app.UIDisplayAxes, 'Y')
            zlabel(app.UIDisplayAxes, 'Z')
            app.UIProfileAxes = uiaxes(app.DisplaysettingsPanel);
            app.UIChannelTable = uitable(app.DisplaysettingsPanel);
            app.UIChannelTable.ColumnName = {'Display'; 'Name'; 'Levels'; 'RGB'; 'Weight'; 'Auto'};
            app.UIChannelTable.RowName = {};
            app.UIChannelTable.SelectionChangedFcn = createCallbackFcn(app, @UIChannelTableSelectionChanged, true);
            app.ZoomLabel = uilabel(app.DisplaysettingsPanel);
            app.ZoomLabel.Text = 'Zoom';
            app.ZoomSlider = uislider(app.DisplaysettingsPanel);
            app.ZoomSlider.ValueChangingFcn = createCallbackFcn(app, @ZoomSliderValueChanging, true);
            app.FrameSliderLabel = uilabel(app.DisplaysettingsPanel);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.DisplaysettingsPanel);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.LineIntensityprofileButton = uibutton(app.DisplaysettingsPanel, 'state');
            app.LineIntensityprofileButton.ValueChangedFcn = createCallbackFcn(app, @LineIntensityprofileButtonValueChanged2, true);
            app.LineIntensityprofileButton.Text = 'Line Intensity profile';
            app.FrameEditField = uieditfield(app.DisplaysettingsPanel, 'numeric');
            app.FrameEditField.ValueChangedFcn = createCallbackFcn(app, @FrameEditFieldValueChanged, true);
            app.ChannelPanel = uipanel(app.DisplaysettingsPanel);
            app.ChannelPanel.Title = 'Channel';
            app.LowLabel = uilabel(app.ChannelPanel);
            app.LowLabel.Text = 'Low';
            app.LowDisplaySlider = uislider(app.ChannelPanel);
            app.LowDisplaySlider.Limits = [0 4.8];
            app.LowDisplaySlider.MajorTicks = [0 1 2 3 4];
            app.LowDisplaySlider.MajorTickLabels = {'1', '10', '100', '1000', '10000'};
            app.LowDisplaySlider.ValueChangingFcn = createCallbackFcn(app, @LowDisplaySliderValueChanging, true);
            app.LowDisplaySlider.MinorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2 2.2 2.4 2.6 2.8 3 3.2 3.4 3.6 3.8 4 4.2 4.4 4.6 4.8];
            app.HighLabel = uilabel(app.ChannelPanel);
            app.HighLabel.Text = 'High';
            app.HighDisplaySlider = uislider(app.ChannelPanel);
            app.HighDisplaySlider.Limits = [0 4.8];
            app.HighDisplaySlider.MajorTicks = [0 1 2 3 4];
            app.HighDisplaySlider.MajorTickLabels = {'1', '10', '100', '1000', '10000'};
            app.HighDisplaySlider.ValueChangingFcn = createCallbackFcn(app, @HighDisplaySliderValueChanging, true);
            app.HighDisplaySlider.MinorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2 2.2 2.4 2.6 2.8 3 3.2 3.4 3.6 3.8 4 4.2 4.4 4.6 4.8];
            app.HighDisplaySlider.Value = 1;
            app.WeightSliderLabel = uilabel(app.ChannelPanel);
            app.WeightSliderLabel.Text = 'Weight';
            app.WeightSlider = uislider(app.ChannelPanel);
            app.WeightSlider.Limits = [0 1];
            app.WeightSlider.MajorTicks = [0 0.5 1];
            app.WeightSlider.ValueChangingFcn = createCallbackFcn(app, @WeightSliderValueChanging, true);
            app.WeightSlider.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.WeightSlider.Value = 1;
            app.ColorPickerLabel = uilabel(app.ChannelPanel);
            app.ColorPickerLabel.Text = 'Color Picker';
            app.ChannelColorPicker = uicolorpicker(app.ChannelPanel);
            app.ChannelColorPicker.ValueChangedFcn = createCallbackFcn(app, @ChannelColorPickerValueChanged, true);
            app.SelectedChannelLabel = uilabel(app.ChannelPanel);
            app.OverlayCheckBox = uicheckbox(app.ChannelPanel);
            app.OverlayCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlayCheckBoxValueChanged, true);
            app.OverlayCheckBox.Text = 'Overlay Channels';
            app.OverlayCheckBox.Value = true;
            app.ShapeButton = uibutton(app.DisplaysettingsPanel, 'state');
            app.ShapeButton.ValueChangedFcn = createCallbackFcn(app, @ShapeButtonValueChanged, true);
            app.ShapeButton.Text = 'Shape intensity';
            app.ROisPanel = uipanel(app.ScoreAppUIFigure);
            app.ROisPanel.Title = 'ROis';
            app.UIROITable = uitable(app.ROisPanel);
            app.UIROITable.ColumnName = {'Display'; 'Name'; 'Size'};
            app.UIROITable.RowName = {};
            app.UIROITable.ColumnEditable = [true false false];
            app.DataSettingsPanel = uipanel(app.ScoreAppUIFigure);
            app.DataSettingsPanel.Title = 'Data settings';
            app.UIDataTable = uitable(app.DataSettingsPanel);
            app.UIDataTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UIDataTable.RowName = {};
            app.DataPanel = uipanel(app.ScoreAppUIFigure);
            app.DataPanel.Title = 'Data';
            app.UIDataAxes = uiaxes(app.DataPanel);
            title(app.UIDataAxes, 'Title')
            xlabel(app.UIDataAxes, 'X')
            ylabel(app.UIDataAxes, 'Y')
            zlabel(app.UIDataAxes, 'Z')
            % Show the figure after all components are created
            app.ScoreAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = score(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ScoreAppUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ScoreAppUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScoreAppUIFigure)
        end
    end
end